classdef StahlDataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StahlDataAnalysisUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        EngineClassDropDown             matlab.ui.control.DropDown
        SpecialboxCheckBox              matlab.ui.control.CheckBox
        CollapseFactorBetaEditField     matlab.ui.control.NumericEditField
        CollapseFactorBetaEditFieldLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        LoadFileButton                  matlab.ui.control.Button
        DatasetInputLabel               matlab.ui.control.Label
        CalculateCheckBox               matlab.ui.control.CheckBox
        CalculateCheckBox_2             matlab.ui.control.CheckBox
        TargetFuelmdotEditField         matlab.ui.control.NumericEditField
        TargetFuelmdotEditFieldLabel    matlab.ui.control.Label
        TargetLOXmdotEditField          matlab.ui.control.NumericEditField
        TargetLOXmdotEditFieldLabel     matlab.ui.control.Label
        CHPTTargetEditField             matlab.ui.control.NumericEditField
        CHPTTargetEditFieldLabel        matlab.ui.control.Label
        FIPTTargetEditField             matlab.ui.control.NumericEditField
        FIPTTargetEditFieldLabel        matlab.ui.control.Label
        OIPTTargetEditField             matlab.ui.control.NumericEditField
        OIPTTargetEditFieldLabel        matlab.ui.control.Label
        FTPTTargetEditField             matlab.ui.control.NumericEditField
        FTPTTargetEditFieldLabel        matlab.ui.control.Label
        OTPTTargetEditField             matlab.ui.control.NumericEditField
        OTPTTargetEditFieldLabel        matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        LOXLN2densityEditField          matlab.ui.control.NumericEditField
        LOXLN2densityEditFieldLabel     matlab.ui.control.Label
        fmmultiplierTextArea            matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        fmmultiplierTextAreaLabel       matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        LOXLeadEditField_3              matlab.ui.control.EditField
        LOXLeadEditField_3Label         matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        FMVTimeEditField                matlab.ui.control.NumericEditField
        FMVTimeEditFieldLabel           matlab.ui.control.Label
        OMVTimeEditField                matlab.ui.control.NumericEditField
        OMVTimeEditFieldLabel           matlab.ui.control.Label
        LOXLeadEditField                matlab.ui.control.NumericEditField
        LOXLeadLabel                    matlab.ui.control.Label
        versionLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        madebyJacksonStahlLabel         matlab.ui.control.Label
        OrificeandinjectorusedinterchangablyinoutputsLabel  matlab.ui.control.Label
        TEditField_2                    matlab.ui.control.NumericEditField
        TLabel                          matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        SamplingRateTextAreaLabel       matlab.ui.control.Label
        SamplingRateTextArea            matlab.ui.control.TextArea
        sLabel_3                        matlab.ui.control.Label
        fmvflowdurTextAreaLabel         matlab.ui.control.Label
        fmvflowdurTextArea              matlab.ui.control.TextArea
        sLabel_2                        matlab.ui.control.Label
        omvflowdurTextAreaLabel         matlab.ui.control.Label
        omvflowdurTextArea              matlab.ui.control.TextArea
        fuelsyscdaTextArea              matlab.ui.control.NumericEditField
        fuelorfcdaTextArea              matlab.ui.control.NumericEditField
        venturicdTextArea               matlab.ui.control.NumericEditField
        loxsyscdaTextArea               matlab.ui.control.NumericEditField
        loxorfcdaTextArea               matlab.ui.control.NumericEditField
        psiLabel                        matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        cm2Label_4                      matlab.ui.control.Label
        cm2Label_3                      matlab.ui.control.Label
        fuelsyscdaTextAreaLabel         matlab.ui.control.Label
        fuelorfcdaTextAreaLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        cm2Label_2                      matlab.ui.control.Label
        cm2Label                        matlab.ui.control.Label
        venturicdTextAreaLabel          matlab.ui.control.Label
        loxsyscdaTextAreaLabel          matlab.ui.control.Label
        loxorfcdaTextAreaLabel          matlab.ui.control.Label
        PNPTTextAreaLabel               matlab.ui.control.Label
        PNPTTextArea                    matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        datacsvEditFieldLabel           matlab.ui.control.Label
        FMVTimeTextAreaLabel            matlab.ui.control.Label
        datacsvEditField                matlab.ui.control.EditField
        WaterCheckBox                   matlab.ui.control.CheckBox
        JetACheckBox                    matlab.ui.control.CheckBox
        LN2CheckBox                     matlab.ui.control.CheckBox
        LOXCheckBox                     matlab.ui.control.CheckBox
        FMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextAreaLabel            matlab.ui.control.Label
        PlotsHETSTab                    matlab.ui.container.Tab
        PlotEntryButton_2               matlab.ui.control.Button
        SeparateentriesbyspacesEditField_2  matlab.ui.control.EditField
        SeparateentriesbyspacesEditField_2Label  matlab.ui.control.Label
        CEfficiencyButton               matlab.ui.control.Button
        SaveOpenButton                  matlab.ui.control.Button
        PRPTFCheckBox                   matlab.ui.control.CheckBox
        PRPTOCheckBox                   matlab.ui.control.CheckBox
        ShowdatatipsCheckBox_2          matlab.ui.control.CheckBox
        ShowdatatipsCheckBox            matlab.ui.control.CheckBox
        WithPressuresCheckBox           matlab.ui.control.CheckBox
        ODPCheckBox                     matlab.ui.control.CheckBox
        FLOWCheckBox                    matlab.ui.control.CheckBox
        PNPTCheckBox                    matlab.ui.control.CheckBox
        TemperaturesButton              matlab.ui.control.Button
        ShowinputsCheckBox              matlab.ui.control.CheckBox
        AvgFuelOrfCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelOrfCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelSysCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelOrfCdASysCdAEditField    matlab.ui.control.EditField
        AvgFuelOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAOrificeEditField   matlab.ui.control.EditField
        AvgFuelSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXOrfCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdASysCdAEditField     matlab.ui.control.EditField
        AvgLOXOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXSysCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAOrificeEditField    matlab.ui.control.EditField
        AvgLOXSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        cm2Label_12                     matlab.ui.control.Label
        cm2Label_11                     matlab.ui.control.Label
        cm2Label_10                     matlab.ui.control.Label
        cm2Label_9                      matlab.ui.control.Label
        cm2Label_8                      matlab.ui.control.Label
        cm2Label_7                      matlab.ui.control.Label
        cm2Label_6                      matlab.ui.control.Label
        cm2Label_5                      matlab.ui.control.Label
        kgsLabel_6                      matlab.ui.control.Label
        avgfuelmdotfmTextAreaLabel      matlab.ui.control.Label
        avgfuelmdotfmTextArea           matlab.ui.control.TextArea
        kgsLabel_5                      matlab.ui.control.Label
        avgfuelmdotsysTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotsysTextArea          matlab.ui.control.TextArea
        kgsLabel_4                      matlab.ui.control.Label
        avgfuelmdotorfTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotorfTextArea          matlab.ui.control.TextArea
        kgsLabel_3                      matlab.ui.control.Label
        avgloxmdotventuriTextAreaLabel  matlab.ui.control.Label
        avgloxmdotventuriTextArea       matlab.ui.control.TextArea
        kgsLabel_2                      matlab.ui.control.Label
        avgloxmdotsysTextAreaLabel      matlab.ui.control.Label
        avgloxmdotsysTextArea           matlab.ui.control.TextArea
        kgsLabel                        matlab.ui.control.Label
        avgloxmdotorfTextAreaLabel      matlab.ui.control.Label
        avgloxmdotorfTextArea           matlab.ui.control.TextArea
        EngineDataButton                matlab.ui.control.Button
        CloseAllButton                  matlab.ui.control.Button
        ShowinputsCheckBox_2            matlab.ui.control.CheckBox
        FINPTCheckBox                   matlab.ui.control.CheckBox
        FLPTCheckBox                    matlab.ui.control.CheckBox
        CHPTCheckBox                    matlab.ui.control.CheckBox
        FIPTCheckBox                    matlab.ui.control.CheckBox
        FRUNPTCheckBox                  matlab.ui.control.CheckBox
        FTPTCheckBox                    matlab.ui.control.CheckBox
        OIPTCheckBox                    matlab.ui.control.CheckBox
        ORUNPTCheckBox                  matlab.ui.control.CheckBox
        OTPTCheckBox                    matlab.ui.control.CheckBox
        PRPT2CheckBox                   matlab.ui.control.CheckBox
        PRPT1CheckBox                   matlab.ui.control.CheckBox
        ThrustButton                    matlab.ui.control.Button
        ValvesButton                    matlab.ui.control.Button
        CdAsButton                      matlab.ui.control.Button
        mdotsButton                     matlab.ui.control.Button
        PressureLaddersButton           matlab.ui.control.Button
        PurgeButton                     matlab.ui.control.Button
        defunctButton                   matlab.ui.control.Button
        PlotsVespulaTab                 matlab.ui.container.Tab
        PlotEntryButton                 matlab.ui.control.Button
        VespulaNoteTextArea             matlab.ui.control.TextArea
        VespulaNoteTextAreaLabel        matlab.ui.control.Label
        TemperaturesButton_2            matlab.ui.control.Button
        SeparateentriesbyspacesEditField  matlab.ui.control.EditField
        PlotEntryLabel                  matlab.ui.control.Label
        PRPTButton                      matlab.ui.control.Button
        PNPTButton                      matlab.ui.control.Button
        BangBangsButton                 matlab.ui.control.Button
        ThrustButton_2                  matlab.ui.control.Button
        LOXPTsButton                    matlab.ui.control.Button
        FuelPTsButton                   matlab.ui.control.Button
        mdotsButton_2                   matlab.ui.control.Button
        CdAsButton_2                    matlab.ui.control.Button
        LoadInputsTab                   matlab.ui.container.Tab
        DatainfolderonlySp25VespulaRockettestsCheckBox  matlab.ui.control.CheckBox
        FlowMeterScalingCorrectionCheckBox  matlab.ui.control.CheckBox
        AVINouveauCheckBox              matlab.ui.control.CheckBox
        FlowMeterremovedCheckBox        matlab.ui.control.CheckBox
        FMVTimeEditField_2              matlab.ui.control.NumericEditField
        FMVTimeEditField_2Label         matlab.ui.control.Label
        OMVTimeEditField_2              matlab.ui.control.NumericEditField
        OMVTimeEditField_2Label         matlab.ui.control.Label
        LOXLeadEditField_4              matlab.ui.control.NumericEditField
        LOXLeadEditField_4Label         matlab.ui.control.Label
        SwitchORUNandFRUNCheckBox       matlab.ui.control.CheckBox
        BepatientLabel                  matlab.ui.control.Label
        NotesLabel                      matlab.ui.control.Label
        TargetFuelmdotEditField_2       matlab.ui.control.NumericEditField
        TargetFuelmdotEditField_2Label  matlab.ui.control.Label
        TargetLOXmdotEditField_2        matlab.ui.control.NumericEditField
        TargetLOXmdotEditField_2Label   matlab.ui.control.Label
        CHPTTargetEditField_2           matlab.ui.control.NumericEditField
        CHPTTargetEditField_2Label      matlab.ui.control.Label
        FIPTTargetEditField_2           matlab.ui.control.NumericEditField
        FIPTTargetEditField_2Label      matlab.ui.control.Label
        OIPTTargetEditField_2           matlab.ui.control.NumericEditField
        OIPTTargetEditField_2Label      matlab.ui.control.Label
        FTPTTargetEditField_2           matlab.ui.control.NumericEditField
        FTPTTargetEditField_2Label      matlab.ui.control.Label
        OTPTTargetEditField_2           matlab.ui.control.NumericEditField
        OTPTTargetEditField_2Label      matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetLabel                    matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        FuelSystemCdAEditField          matlab.ui.control.NumericEditField
        FuelSystemCdAEditFieldLabel     matlab.ui.control.Label
        FuelInjectorCdAEditField        matlab.ui.control.NumericEditField
        FuelInjectorCdAEditFieldLabel   matlab.ui.control.Label
        LOXSystemCdAEditField           matlab.ui.control.NumericEditField
        LOXSystemCdAEditFieldLabel      matlab.ui.control.Label
        LOXInjectorCdAEditField         matlab.ui.control.NumericEditField
        LOXInjectorCdAEditFieldLabel    matlab.ui.control.Label
        PowerPointBetaTab               matlab.ui.container.Tab
        slidecounterEditField           matlab.ui.control.EditField
        slidecounterEditFieldLabel      matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        MakePowerPointButton            matlab.ui.control.Button
        ChangelogTab                    matlab.ui.container.Tab
        FrankButton                     matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        times % Description
        data_name 
        file
        filepath
        lox             % Switch variable for what oxidizer is used on oxidizer side
        jeta            % Switch variable for what fuel is used on fuel side
        %sensors begin
        PRPT1           % Pressurant Gas Pressure Transducer - 1
        PRPT2           % Pressurant Gas Pressure Transducer - 2
        PRPTO           % Pressurant Gas Pressure Transducer - Oxidizer
        PRPTF           % Pressurant Gas Pressure Transducer - Fuel
        %fuel side
        FIPT            % Fuel Injector Pressure Transducer
        FIPT2
        FLPT            % 
        FINPT           % Fuel
        FTPT            % Fuel Tank Pressure Transducer
        FMV             % Fuel Main Valve
        FRUNPT          % Fuel Run Line Pressure Transducer
        FRUNPT2
        FBANG           % Fuel Bang Bang
        FLOW            % Fuel Flow Meter
        FTLC1           % Fuel Tank Load Cell 1
        FTLC2           % Fuel Tank Load Cell 2
        FTUTC           % Fuel Tank Ullage Thermocouple
        PUF             % Fuel Purge
        FQD             
        FSWV
        FVNT
        FBB
        FURTD
        %lox side
        OIPT            % Oxidizer Injector Pressure Transducer
        OMV             % Oxidizer Main Valve
        OTPT            % Oxidizer Tank Pressure Transducer
        ORUNPT          % Oxidizer Run Line Pressure Transducer
        OBANG           % Oxidizer Bang Bang
        ODP             % Oxidizer Differential Pressure
        DPPT1
        DPPT2
        OTLC1           % Oxidizer Tank Load Cell 1
        OTLC2           % Oxidizer Tank Load Cell 2
        OFMTC           % Oxidizer Flow Meter (Venturi) Thermocouple
        OTBTC           % Oxidizer Tank Bottom Thermocouple
        OTTC1           % Oxidizer Tank Thermocouple 1
        OTTC2           % Oxidizer Tank Thermocouple 2
        OTTC3           % Oxidizer Tank Thermocouple 3
        OITC            % Oxidizer Injector Thermocouple
        PUO             % Oxidizer Purge
        OURTD
        ORTD
        OIRTD
        OQD
        OSWV
        OVNT
        OBB
        %ground valves
        GOFILL
        GOVNT2
        GRISO1
        GRISO2
        GRISO3
        GRISO4
        GPRFILL
        GPRVNT
        GPNVNT
        PRQD
        %others
        PNPT            % Pneumatics Pressure Transducer
        PNPT2           % Pneumatics Pressure Transducer 2
        PUPT2           % Purge Pressure Transducer 2
        THRUST          % Thrust Structure Load Cell
        ISP             % Specific Impulse
        ISPavg_values   % Specific Impulse using averaged values
        CHPT            % Chamber Pressure Transducer
        CHPT2
        ETC1            % Engine Thermocouple 1
        ETC2            % Engine Thermocouple 2
        ETC3            % Engine Thermocouple 3
        ETC4            % Engine Thermocouple 4
        ETC5
        ETC6
        ETC7
        ETC8
        ETC9
        ETC10
        ETC11
        ETC12
        ETC13
        ETC14
        ETC15
        ETC16
        ETC17
        ETC18
        ETC19
        ETC20
        ETC21
        HFTC1           % Heat Flux Thermocouple 1
        HFTC2           % Heat Flux Thermocouple 2
        PNQD
        PNVNT
        PRPT
        PRRTD
        PRVNT
        IGNITER1        % Ingiter Voltage 1 (Nicrome & Epoxy)
        IGNITER2        % Igniter Voltage 2 (Solid Rocket Motor)
        SENSE           % Sense Wire (Voltage)
        %lox variables
        lox_mdot_cda               % Liquid Oxygen Mass Flow calculated with Injector
        lox_mdot_sys_cda           % Liquid Oxygen Mass Flow calculated with System CdA
        lox_mdot_venturi           % Liquid Oxygen Mass FLow calculated with Venturi Flow Meter
        avg_lox_mdot_orifice       % Average Value for the Liquid Oxygen Mass Flow calculated with Injector
        avg_lox_mdot_sys_cda       % Average Value for the Liquid Oxygen Mass Flow calculated with System CdA
        avg_lox_mdot_venturi       % Average Value for the Liquid Oxygen Mass Flow calculated with Venturi Flow Meter
        lox_sys_cda_orifice        % Liquid Oxygen System CdA calculated from Injector mdot
        lox_sys_cda_venturi        % Liquid Oxygen System CdA calculated from Venturi mdot
        avg_lox_sys_cda_orifice    % Average Value for the Liquid Oxygen System CdA calculated from Injector mdot
        avg_lox_sys_cda_venturi    % Average Value for the Liquid Oxygen System CdA calculated from Ventrui mdot
        lox_orf_cda_sys_cda        % Liquid Oxygen Orifice CdA calculated from the System mdot & Injector Pressure Drop
        lox_orf_cda_venturi        % Liquid Oxygen Orifice CdA calculated from the Venturi mdot & Injector Pressure Drop
        avg_lox_orf_cda_venturi    % Average Value for the Liquid Oxygen Injector CdA calculated from the Venturi mdot & Injector Pressure Drop
        avg_lox_orf_cda_sys_cda    % Average Value for the Liquid Oxygen Injector CdA calculated from the System mdot& Injector Pressure Drop
        %fuel variables
        fuel_mdot_cda              % Fuel mdot calculated from Injector CdA
        fuel_mdot_sys_cda          % Fuel mdot calculated from System CdA
        fuel_mdot_fm               % Fuel mdot calculated from Flow Meter CdA
        avg_fuel_mdot_orifice      % Average Value for the Fuel mdot calculated from Injector CdA
        avg_fuel_mdot_sys_cda      % Average Value for the Fuel mdot calculated from System CdA
        avg_fuel_mdot_fm           % Average Value for the Fuel mdot calculated from Flow Meter CdA
        fuel_sys_cda_orifice       % Fuel System CdA calculated from the Injector mdot
        fuel_sys_cda_fm            % Fuel System CdA calculated from the Flow Meter mdot
        avg_fuel_sys_cda_orifice   % Average Value for the Fuel System CdA calculated from the Injector mdot
        avg_fuel_sys_cda_fm        % Average Value for the Fuel System CdA calculated from the Flow Meter mdot
        fuel_orf_cda_sys_cda       % Fuel Orifice CdA calculated from the System mdot & Injector Pressure Drop
        fuel_orf_cda_fm            % Fuel Orifice CdA calculated from Flow Meter mdot & Injector Pressure Drop
        avg_fuel_orf_cda_sys_cda   % Average Value for the Fuel Injector CdA calculated from the System mdot & Injector Pressure Drop
        avg_fuel_orf_cda_fm        % Average Value for the Fuel Injector CdA calculated from the Flow Meter mdot & Injector Pressure Drop
        %inputs
        lox_orifice_cda            % Known or entered Liquid Oxygen Injector CdA
        lox_sys_cda                % Known or entered Liquid Oxygen System CdA
        venturi_cd                 % Known or entered Ventui Coefficient of Discharge  
        fuel_orifice_cda           % Known or entered Fuel Injector CdA
        fuel_sys_cda               % Known or entered Fuel System CdA           
        throat_area                % Nozzle Throat Area
        fm_multiplier
        %timing
        tzero
        tend
        %averages
        meanCHPT                   % Average Value of the Chamber Pressure Transducer
        meanOIPT                   % Average Value of the Oxidizer Injector Pressure Transducer
        meanFIPT                   % Average Value of the Fuel Injector Pressure Transducer

        avg_THRUST                 % Average Value of the Thrust Load Cell
        deltat                     % same as dt, time step.

        JacksonMR
    end
    
    methods (Access = public)
        
        function LoadNewData(app, data)                                      % Defines how GUI imports in Data from Hotfires/Static Fires
            app.TextArea.Value = "Processing (5/5)";
            pause(0.01); % This makes the Running box work
            if sum(data(1:13)~=['Loaded file: '])~=0                         % Loaded file display
                app.data_name = data;
                if data(end-3:end)==['.mat']
                    data = importdata(data);
                elseif data(end-3:end)==['.csv']
                    data = readcell(data);
                else
                    app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                    pause(0.1);
                    return
                end
            else
                data = fullfile(app.filepath, app.file);
                app.data_name = app.file;
                if data(end-3:end)==['.mat']
                    data = importdata(data);
                elseif data(end-3:end)==['.csv']
                    data = readcell(data);
                else
                    app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                    pause(0.1);
                    return
                end
            end
            
            app.TextArea.Value = "Running (1/12)";
            pause(0.01); % This makes the Running box work

            %Inputs, all TextArea values, directly entered/loaded into the GUI
            % LOX
            app.lox_orifice_cda = app.loxorfcdaTextArea.Value;               % cm^2, inputted injector CDA lox side
            app.lox_sys_cda = app.loxsyscdaTextArea.Value;                   % cm^2, inputted system CDA lox sie
            app.venturi_cd = app.venturicdTextArea.Value;                    % inputted venturi coefficient of discharge lox side
            
            % Fuel
            app.fuel_orifice_cda = app.fuelorfcdaTextArea.Value;             % cm^2, inputted injector CDA fuel side
            app.fuel_sys_cda = app.fuelsyscdaTextArea.Value;                 % cm^2, inputted system CDA fuel side
            if app.CalculateCheckBox.Value==true
                if app.TargetFuelmdotEditField.Value < 0.83
                    app.fmmultiplierTextArea.Value = 0.5625 * app.TargetFuelmdotEditField.Value + 0.53;
                    pause(0.1);
                else
                    app.fmmultiplierTextArea.Value = 1.0;
                    pause(0.1);
                end
            end
            app.fm_multiplier = app.fmmultiplierTextArea.Value;
            
            % LOX Density, defines the density of the oxidizer based on what lox we use
            switch app.lox
                case 2
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, add cool prop, (LOX)
                case 1
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, (LN2)
            end
            
            % Fuel Density
            switch app.jeta
                case 2
                    fuel_density = 800 % kg/m^3, (Kerosene (Jet-A or RP-1))
                case 1
                    fuel_density = 1000 % kg/m^3, (Water)
            end
            
            % Engine throat area
            switch app.EngineClassDropDown.Value       % On GUI display, the Engine Class dropdown under the import file line
                case 'Subscale - HETS'
                    app.throat_area = 0.001481663339628684; % m^2,   Subscale throat area
                otherwise
                    app.throat_area = 0.00456; %% for V heatsink:0.00390296138; % m^2,      Vespula throat area
            end
            ideal_c_star = 1650; % m/s
            
            app.TextArea.Value = "Running (2/12)";
            pause(0.01); % This makes the Running box work

            % Interval
            %t_before_omv = app.TEditField.Value;           % s, time before OMV opens to plot data
            t_start_avg = 1.5;                              % s, T+ time to pull average
            t_end_avg = 0.1;                                % s, time before OMV closes to pull average
            t_before_fmv = app.TEditField.Value;            % s, time before fmv opens to plot data
            t_plus = app.TEditField_2.Value;                % s, T+ time when to stop plotting
            
            %Index Sensors and Valves
            columns = size(data,2);
            time_index = 1;
            
            % Initialize sensors index to prevent error when sensor is missing, since these sensors are not ALWAYS mapped/cared about during testing.
            chpt_index = 1;
            chpt2_index = 1;
            ftlc2_index = 1;
            frunpt2_index = 1;
            ftlc1_index = 1;
            otlc2_index = 1;
            otlc1_index = 1;
            otutc_index = 1;
            otbtc_index = 1;
            ofmtc_index = 1;
            ftutc_index = 1;
            dppt1_index = 1;
            dppt2_index = 1;
            thrust_index = 1;
            odp2_index = 1;
            etc1_index = 1;
            etc2_index = 1;
            etc3_index = 1;
            etc4_index = 1;
            etc5_index = 1;
            etc6_index = 1;
            etc7_index = 1;
            etc8_index = 1;
            etc9_index = 1;
            etc11_index = 1;
            etc12_index = 1;
            etc13_index = 1;
            etc14_index = 1;
            etc15_index = 1;
            etc16_index = 1;
            etc17_index = 1;
            etc18_index = 1;
            etc19_index = 1;
            etc20_index = 1;
            etc21_index = 1;
            hftc1_index = 1;
            hftc2_index = 1;
            pnpt2_index = 1;
            prpt1_index = 1;
            prpto_index = 1;
            prptf_index = 1;
            igniter2_index = 1;
            
            app.TextArea.Value = "Running (3/12)";
            pause(0.01); % This makes the Running box work

            % Add sensors and valves into for loop
            % Use below syntax to get the index (column location) of any recorded variable in the .csv/.mat file, such as FTPT
            % or FBANG_V, keep the i - 1 (first column is timestamp column)
            
            class(data)

            for i = 1:columns
                if contains(data{1,i}, ['FTPT'])  % if the text (string) in the first row reads 'FTPT' = is the column that has the data for FTPT:
                    ftpt_index = i-1;           % that column of FTPT is recorded but minus one for when the timestamp column is removed
                end
            
                if contains(data{1,i}, ['OTPT'])  % records the index for OTPT
                    otpt_index = i-1;
                end   
            
                if contains(data{1,i}, ['FMV'])   % records the index for FMV 
                    fmv_index = i-1;
                end
            
                if contains(data{1,i}, ['OMV'])   % records the index for OMV
                    omv_index = i-1;
                end
            
                if contains(data{1,i}, ['PUO'])   % records the index for PUO
                    puo_index = i-1;
                end
            
                if contains(data{1,i}, ['PUF'])   % records the index for PUF
                    puf_index = i-1;
                end
            
                if contains(data{1,i}, ['OIPT'])  % records the index for OIPT
                    oipt_index = i-1;
                end
            
                if contains(data{1,i}, ['FIPT'])  % records the index for FIPT
                    fipt_index = i-1;
                end
            
                if contains(data{1,i}, ['ORUNPT']) % records the index for ORUNPT
                    orunpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FRUNPT']) | strcmp(data{1,i},['FRUNPT(psi)'])% records the index for FRUNPT
                    frunpt_index = i-1;
                end

                if contains(data{1,i}, ['FRUNPT2']) % records the index for FRUNPT
                    frunpt2_index = i-1;
                end
            
                if contains(data{1,i}, ['FBANG_V']) % records the index for voltage of FBANG
                    fbang_index = i-1;
                end
            
                if contains(data{1,i}, ['OBANG_V']) % records the index for voltage of OBANG
                    obang_index = i-1;
                end
            
                if contains(data{1,i}, ['PRPT1']) % records the index for PRPT1
                   prpt1_index = i-1;
                end
            
                if contains(data{1,i}, ['PRPT2']) % records the index for PRPT2
                   prpt2_index = i-1;
                end

                if contains(data{1,i}, ['PRPTO']) % records the index for PRPTO
                   prpto_index = i-1;
                end
            
                if contains(data{1,i}, ['PRPTF']) % records the index for PRPTF
                   prptf_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PNPT']) % records the index for PNPT
                   pnpt_index = i-1;
                end

                if strcmp(data{1,i}, ['PNPT(psi)']) % records the index for PNPT(psi)
                   pnpt_index = i-1;
                end
                
                if contains(data{1,i}, ['PNPT2']) % records the index for PNPT2
                   pnpt2_index = i-1;
                end

                if contains(data{1,i}, ['FLOW']) % records the index for FLOW
                   flow_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ODP']) % records the index for ODP
                   odp_index = i-1;
                end

                if strcmp(data{1,i}, ['ODP(psi)']) % records the index for ODP
                   odp_index = i-1;
                end
            
                if contains(data{1,i}, ['ODP2']) % records the index for ODP2
                   odp2_index = i-1;
                end
            
                if contains(data{1,i}, ['FTLC1']) % records the index for FTLC1
                   ftlc1_index = i-1;
                end
            
                if contains(data{1,i}, ['FTLC2']) % records the index for FTLC2
                   ftlc2_index = i-1;
                end
            
                if contains(data{1,i}, ['OTLC1']) % records the index for OTLC1
                   otlc1_index = i-1;
                end
            
                if contains(data{1,i}, ['OTLC2']) % records the index for OTLC2
                   otlc2_index = i-1;
                end
            
                if contains(data{1,i}, ['OFMTC']) % records the index for OFMTC
                   ofmtc_index = i-1;
                end
            
                if contains(data{1,i}, ['OTUTC']) % records the index for OTUTC
                   otutc_index = i-1;
                end
            
                if contains(data{1,i}, ['OTBTC']) % records the index for OTBTC
                   otbtc_index = i-1;
                end
            
                if contains(data{1,i}, ['OTTC1']) % records the index for OTTC1
                   ottc1_index = i-1;
                end
            
                if contains(data{1,i}, ['OTTC2']) % records the index for OTTC2
                   ottc2_index = i-1;
                end
            
                if contains(data{1,i}, ['OTTC3']) % records the index for OTTC3
                   ottc3_index = i-1;
                end
            
                if contains(data{1,i}, ['FTUTC']) % records the index for FTUTC
                   otbtc_index = i-1;
                end
            
                if contains(data{1,i}, ['DPPT1']) % records the index for DPPT1
                   dppt1_index = i-1;
                end
            
                if contains(data{1,i}, ['DPPT2']) % records the index for DPPT2
                   dppt2_index = i-1;
                end
            
                if contains(data{1,i}, ['Thrust']) % records the index for Thrust
                   thrust_index = i-1;
                end
            
                if contains(data{1,i}, ['CHPT']) | strcmp(data{1,i}, ['CHPT(psi)']) | contains(data{1,i}, ['CHPT1']) % records the index for CHPT
                   chpt_index = i-1;
                end

                if contains(data{1,i}, ['CHPT2']) % records the index for CHPT
                   chpt2_index = i-1;
                end

                if contains(data{1,i}, ['FLPT']) % records the index for FLPT
                   flpt_index = i-1;
                end
            
                if contains(data{1,i}, ['FINPT']) % records the index for FINPT
                   finpt_index = i-1;
                end
            
                if contains(data{1,i}, ['ETC1']) | contains(data{1,i}, ['ETC01']) % Engine Thermocouples could go recorded under either name
                   etc1_index = i-1;
                end
            
                if contains(data{1,i}, ['ETC2']) | contains(data{1,i}, ['ETC02']) % records the index for ETC2
                   etc2_index = i-1;
                end
            
                if contains(data{1,i}, ['ETC3']) | contains(data{1,i}, ['ETC03']) % records the index for ETC3
                   etc3_index = i-1;
                end

                if contains(data{1,i}, ['HFTC1']) % records the index for HFTC1
                   hftc1_index = i-1;
                end
            
                if contains(data{1,i}, ['HFTC2']) % records the index for HFTC2
                   hftc2_index = i-1;
                end
            
                if contains(data{1,i}, ['ETC4']) | contains(data{1,i}, ['ETC04']) % records the index for ETC4
                  etc4_index = i-1;
                end
            
                if contains(data{1,i}, ['Ignitor1']) | strcmp(data{1,i}, ['Ignitor']) % records the index for Ignitor1
                  igniter1_index = i-1;
                end
            
                if contains(data{1,i}, ['Ignitor2']) % records the index for Ignitor2
                  igniter2_index = i-1;
                end
            
                if contains(data{1,i}, ['PUPT2']) % records the index for PUPT2
                  pupt2_index = i-1;
                end
            
                if contains(data{1,i}, ['OITC']) % records the index for OITC
                  oitc_index = i-1;
                end
            
                if contains(data{1,i}, ['Sense']) % records the index for Sense wire
                  sense_index = i-1;
                end

                if contains(data{1,i}, ['ETTC1'])
                   etc1_index = i-1;
                end
                if contains(data{1,i}, ['ETTC3'])
                   etc3_index = i-1;
                end
                if contains(data{1,i}, ['ETTC7'])
                   etc7_index = i-1;
                end
                if contains(data{1,i}, ['ETTC8'])
                   etc8_index = i-1;
                end
                
                if contains(data{1,i}, ['ETC5']) | contains(data{1,i}, ['ETC05']) % records the index for ETC4
                  etc4_index = i-1;
                end

                if contains(data{1,i}, ['ETC6'])
                   etc6_index = i-1;
                end

                if contains(data{1,i}, ['ETC7'])
                   etc7_index = i-1;
                end

                if contains(data{1,i}, ['ETC8'])
                   etc8_index = i-1;
                end
                
                if contains(data{1,i}, ['ETC9'])
                   etc9_index = i-1;
                end
                
                if contains(data{1,i}, ['ETC10'])
                   etc10_index = i-1;
                end

                if contains(data{1,i}, ['ETC11'])
                   etc11_index = i-1;
                end

                if contains(data{1,i}, ['ETC12'])
                   etc12_index = i-1;
                end

                if contains(data{1,i}, ['ETC13'])
                   etc13_index = i-1;
                end

                if contains(data{1,i}, ['ETC14'])
                   etc14_index = i-1;
                end

                if contains(data{1,i}, ['ETC16'])
                   etc16_index = i-1;
                end

                if contains(data{1,i}, ['ETC17'])
                   etc17_index = i-1;
                end

                if contains(data{1,i}, ['ETC18'])
                   etc18_index = i-1;
                end

                if contains(data{1,i}, ['ETC20'])
                   etc20_index = i-1;
                end

                if contains(data{1,i}, ['ETC21'])
                   etc21_index = i-1;
                end

            end
            
            app.TextArea.Value = "Running (4/12) ======="; % –
            pause(0.01); % This makes the Running box work

            % DO NOT TOUCH THIS PART, expect it to take a decent time to run
            % Separates time from other data because its easier to reduce that way
            
            app.times = [data{2:end,time_index}]-data{2,time_index}; % Sets time to start from data start instead of from unix time
            
            pure_data = data(2:end,2:end); % Separated data, removes the first column of times (we already documented it) and removes first row of sensor names (we already indexed the locations of the corresponding values)
            % !!! the importance of this is to make all the entries in pure_data numerical values in order to import them and graph later on

            app.TextArea.Value = "Running (4/12) +======";
            pause(0.01);

            %data formatting, -250 is a null value, consistent with AVI syntax
            pure_data(cellfun(@(x) isa(x, 'missing'), pure_data)) = {'-250.000000'}; % "missing" values for sensors are made -250 (traditional for AVI)
            app.TextArea.Value = "Running (4/12) ++=====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'open'), pure_data)) = {'1.0000000000'}; % "open" states in pure_data are changed to a 1
            app.TextArea.Value = "Running (4/12) +++====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'closed'), pure_data)) = {'0.0000000000'}; % "closed" states in pure_data are changed to a 0
            app.TextArea.Value = "Running (4/12) ++++===";
            pause(0.01);

            function out = ifstrthennum(x)
                x = x{1};
                if ischar(x)
                    x = str2double(x);
                end
                out = x;
            end

            if app.AVINouveauCheckBox.Value
                data2 = arrayfun(@(x) ifstrthennum(x), pure_data, 'UniformOutput', 0);
                print = 'tha reel'
                data2 = cell2mat(data2);
                print = 'WE PRINTING BOIS'
            else
                pure_data(cellfun(@(x) (length(x) < 5), pure_data)) = {'-0.00000000'}; % if the data point has less than 5 sig figs, replaced with 0
                app.TextArea.Value = "Running (4/12) +++++==";
                pause(0.01);
                
                data2 = cellfun(@(x) x(1:5), pure_data,'UniformOutput',false);
                app.TextArea.Value = "Running (4/12) ++++++=";
                pause(0.01);
    
                data2 = str2double(data2); % all strings in data2 are now converted to doubles
            end

            app.TextArea.Value = "Running (4/12) +++++++";
            pause(0.01);
            app.TextArea.Value = "Running (5/12)";
            pause(0.01); % This makes the Running box work

            %Intervals
            if app.jeta~=0
                local_time = [data2(1:end,fmv_index)]; % if the fuel side is flowing, local_time will be referenced from fmv (recall fmv is on 0/1 for closed/open)
            else
                local_time = [data2(1:end,omv_index)]; % if fuel side is not flowing, local_time will be referenced from omv (recall omv is on 0/1 for closed/open)
            end
            if app.CHPT~=0
                app.CHPT = [data2(1:end,chpt_index)]; % assigns CHPT using recorded CHPT index, have to do this before all other variable because of basing timings off of when CHPT is above ambient (combustion has started)
            elseif exist('flpt_index', 'var')==1
                app.CHPT = [data2(1:end,flpt_index)]; % if FLPT exists, it is referenced
            elseif app.lox~=0
                app.CHPT = [data2(1:end,oipt_index)];
            else
                app.CHPT = [data2(1:end,fipt_index)];
            end
            
            dt = (app.times(end)-app.times(1))/length(app.times);    % seconds, change in real time from one point to the next point
            app.deltat = dt;
            sampling_rate = 1/dt;                                    % Hz, how many data samples per second we get
            sampling_rate = round(sampling_rate, 3);                 % rounds to 3 decimal places
            app.SamplingRateTextArea.Value = num2str(sampling_rate); % converts sampling rate to a string and dispalys it on the GUI
            [~,data_start] = max(local_time);                        % records the index time of the max of local_time. Matlab itself will record the first instance of 1 if there are multiple instances. Essentially, data_start is recorded as soon as fmv opens.
            data_start = data_start - round(t_before_fmv/dt);        % adjusts data_start time to however long user wants to graph before FMV opens 
            
            data_end = data_start + round(t_before_fmv/dt) + round(t_plus/dt); % uses data_start and wanted to period to track when data should end (data_end)
            [~,t_zero] = max(app.CHPT>10);                                     % similar method as before, app.CHPT>10 takes all of app.CHPT and converts it to 1 for true (>10psi) and 0 for false (<10psi) and takes the first instance of the max(1=true->>10psi) as t_zero. Thus (t_zero) is the first instance where CHPT>10psi which is when combustion begins
            app.times = app.times - app.times(t_zero);                         % !!!!!re-adjusts app.times to reference t_zero as 0 in app.times
            
            if data_start < 1
                data_start = 1;
            end
            if data_end > length(data2)
                data_end = length(data2)                                      % in case data_end is longer than what the file has to plot, the file will stop plotting once it runs out of data.
            end
            data2 = data2(data_start:data_end, :);                             % !!!!!!!!!data2 is formatted from data_start to data_end
            app.times = app.times(data_start:data_end);                        % !!!!!!!!!gets times and formates it from data_start to data_end
            app.times;

            app.TextArea.Value = "Running (6/12)";
            pause(0.01); % This makes the Running box work

            %Variable Assignment
            % Pressurant
            app.PRPT1 = [data2(1:end,prpt1_index)]; % assigns PRPT1, pulls the whole column of data using its indexed location to pull the corresponding column to PRPT1 in original .csv/.mat file.
            app.PRPT2 = [data2(1:end,prpt2_index)]; % assigns PRPT2
            app.PRPTO = [data2(1:end,prpto_index)]; % assigns PRPT2
            app.PRPTF = [data2(1:end,prptf_index)]; % assigns PRPT2

            if app.jeta ~= 0                              % if fuel is flowing (water or kerosene)
                % Fuel
                app.FIPT = [data2(1:end,fipt_index)];     % assigns FIPT
                app.FTPT = [data2(1:end,ftpt_index)];     % assigns FTPT
                app.FMV = [data2(1:end,fmv_index)];       % assigns FMV
                app.FRUNPT = [data2(1:end,frunpt_index)]; % assigns FRUNPT
                app.FRUNPT2 = [data2(1:end,frunpt2_index)]; % fuck you
                app.FBANG = [data2(1:end,fbang_index)];   % assigns FBANG
                if exist('flow_index', 'var')==1
                    app.FLOW = [data2(1:end,flow_index)];     % assigns FLOW
                end
                app.FTLC1 = [data2(1:end,ftlc1_index)];   % assigns FTLC1
                app.FTLC2 = [data2(1:end,ftlc2_index)];   % assigns FTLC2
                app.FTUTC = [data2(1:end,otutc_index)];   % assigns FTUTC
                app.PUF = [data2(1:end,puf_index)];       % assigns PUF
                if exist('flpt_index', 'var')==1
                    app.FLPT = [data2(1:end,flpt_index)]; % assigns FLPT
                    app.FLPT = app.FLPT-app.FLPT(1);      % tares FLPT
                else
                    app.FLPT = 0;
                end
                if exist('finpt_index', 'var')==1           % only if finpt_index exists
                    app.FINPT = [data2(1:end,finpt_index)]; % assigns FINPT
                    app.FINPT = app.FINPT-app.FINPT(1);     % tares FINPT
                else
                    app.FINPT = 0;
                end
                % Tare
                if app.FIPT(1)<30                         % if initial FIPT value is over 30psi, FIPT is re-tared
                app.FIPT = app.FIPT - app.FIPT(1);        % tares FIPT
                end
                if app.FRUNPT(1)<30                       % if initial FRUNPT value is over 30psi, FRUNPT is re-tared
                app.FRUNPT = app.FRUNPT - app.FRUNPT(1);  % tares FRUNPT
                end

                % CHPT
                app.CHPT = zeros(length(app.FIPT),1);     % CHPT becomes column of zeros the length of FIPT
            end
            
            if app.lox ~= 0 % if LOX side is flowing (LOX or LN2)
                % LOX
                app.OIPT = [data2(1:end,oipt_index)];     % assigns OIPT
                app.OMV = [data2(1:end,omv_index)];       % assigns OMV
                app.OTPT = [data2(1:end,otpt_index)];     % assigns OTPT
                app.ORUNPT = [data2(1:end,orunpt_index)]; % assigns ORUNPT
                app.OBANG = [data2(1:end,obang_index)];   % assigns OBANG
                app.ODP = [data2(1:end,odp_index)];       % assigns ODP
                %app.ODP2 = [data2(1:end,odp2_index)];
                app.DPPT1 = [data2(1:end,dppt1_index)];   % assigns DPPT1
                app.DPPT2 = [data2(1:end,dppt2_index)];   % assigns DPPT2
                app.OTLC1 = [data2(1:end,otlc1_index)];   % assigns OTLC1
                app.OTLC2 = [data2(1:end,otlc2_index)];   % assigns OTLC2
                app.OFMTC = [data2(1:end,ofmtc_index)];   % assigns OFMTC
                %app.OTUTC = [data2(1:end,otutc_index)];
                app.OTBTC = [data2(1:end,otbtc_index)];   % assigns OTBTC
                if exist('ottc1_index', 'var')==1
                    app.OTTC1 = [data2(1:end,ottc1_index)];   % assigns OTTC1
                end
                app.OTTC2 = [data2(1:end,ottc2_index)];   % assigns OTTC2
                app.OTTC3 = [data2(1:end,ottc3_index)];   % assigns OTTC3
                app.OITC = [data2(1:end,oitc_index)];     % assigns OITC
                app.PUO = [data2(1:end,puo_index)];       % assigns PUO
                % Tare
                if app.OIPT(1)<30                         % if initial OIPT value is over 30psi, OIPT is re-tared
                app.OIPT = app.OIPT-app.OIPT(1);          % tares OIPT from initial value
                end
                if app.ORUNPT(1)<30                       % if initial ORUNPT value is over 30psi, ORUNPT is re-tared
                app.ORUNPT = app.ORUNPT-app.ORUNPT(1);    % tares ORUNPT from initial value
                end

                % CHPT
                app.CHPT = zeros(length(app.OIPT),1);     % CHPT becomes column of zeros the length of OIPT
            end
            
            % Pneumatics
            app.PNPT = [data2(1:end,pnpt_index)];   % assigns PNPT
            app.PNPT2 = [data2(1:end,pnpt2_index)]; % assigns PNPT2
            app.CHPT2 = [data2(1:end,chpt2_index)];
            
            % Purge
            app.PUPT2 = [data2(1:end,pupt2_index)]; % assigns PUPT2
            
            if app.jeta==2 && app.lox==2 % if we are hotfiring with KeroLOX
                % Engine
                app.THRUST = [data2(1:end,thrust_index)];      % assigns THRUST
                app.CHPT = [data2(1:end,chpt_index)];          % assigns CHPT
                app.ETC1 = [data2(1:end,etc1_index)];          % assigns ETC1
                app.ETC2 = [data2(1:end,etc2_index)];         % assigns ETC2
                app.ETC3 = [data2(1:end,etc3_index)];          % assigns ETC3
                app.ETC4 = [data2(1:end,etc4_index)];         % assigns ETC4
                app.ETC5 = [data2(1:end,etc5_index)];
                app.ETC6 = [data2(1:end,etc6_index)];
                app.ETC7 = [data2(1:end,etc7_index)];
                app.ETC8 = [data2(1:end,etc8_index)];
                app.ETC9 = [data2(1:end,etc9_index)];
                app.ETC10 = [data2(1:end,etc10_index)];
                app.ETC12 = [data2(1:end,etc12_index)];
                app.ETC13 = [data2(1:end,etc13_index)];
                app.ETC14 = [data2(1:end,etc14_index)];
                app.ETC15 = [data2(1:end,etc15_index)];
                app.ETC16 = [data2(1:end,etc16_index)];
                app.ETC17 = [data2(1:end,etc17_index)];
                app.ETC18 = [data2(1:end,etc18_index)];
                app.ETC19 = [data2(1:end,etc19_index)];
                app.ETC20 = [data2(1:end,etc20_index)];
                app.ETC21 = [data2(1:end,etc21_index)];
                app.HFTC1 = [data2(1:end, hftc1_index)];       % assigns HFTC1
                app.HFTC2 = [data2(1:end, hftc2_index)];       % assigns HFTC2
                app.IGNITER1 = [data2(1:end,igniter1_index)];  % assigns Ignitor 1
                app.IGNITER2 = [data2(1:end,igniter2_index)];  % assigns Ignitor 2
                app.SENSE = [data2(1:end,sense_index)];        % assigns Sense wire
                %Tare
                if app.CHPT(1)<30                              % if CHPT initial reading is over 30psi, CHPT is re-tared
                    app.CHPT = app.CHPT-app.CHPT(1);           % tares CHPT from initial value
                end
            end
            
            app.TextArea.Value = "Running (7/12)";
            pause(0.01); % This makes the Running box work

            if app.CalculateCheckBox_2.Value==true
                %LOX/LN2 Density 
                %the following calculations come from DENSITIES OF SATURATED
                %LIQUID OXYGEN AND NITROGEN, by K. GOLDMAN and N. G.
                %SCRASE, 1968. The expermiment determined a relationship
                %between Temp and density. The value of pressure is a function 
                % of temperature at saturation, so we may determine density 
                % with a single variable.
                switch app.lox
                    case 2 %LOX option is checked, not LN2
                        x = (1 - app.OFMTC(1)/154.77)^(1/3); %Input for regression Function, effectively normalizes the input
                        %154.77 is the provided critical temp of lox, in K
                        %values for coeffs of regression:
                        a = 0.487609;
                        b = -0.203117;
                        c = 4.624474;
                        d = -9.374991;
                        e = 9.517905;
                        f = -3.590726;
                        p = a + b*x + c*x^2 + d*x^3 + e*x^4 + f*x^5; % Regression function for LOX per paper cited above
                        lox_density = p*1000;
                        if isreal(p) & ~isnan(p)
                            app.LOXLN2densityEditField.Value = p*1000;
                        else
                            app.TextArea.Value = "Error: LOX is not present in this dataset.";
                            pause(0.01); % This makes the Running box work
                            return
                        end
                        pause(0.1);
                    case 1 %LN2 option is checked, not LOX
                        x = (1 - app.OFMTC(1)/126.26)^(1/3); %Input for regression Function, effectively normalizes the input
                        %126.26 is the provided critical temp of lox, in K
                        %values for coeffs of regression:
                        a = 0.294536009;
                        b = 0.65818693;
                        c = -0.373977236;
                        d = 0.665186539;
                        e = 0.167862430;
                        f = 1.32508915;
                        g = -4.81545606;
                        h = 3.41622168;
                        p = a + b*x + c*x^2 + d*x^3 + e*x^4 + f*x^5 + g*x^6 + h*x^7;  % Regression function for LN2 per paper cited above
                        lox_density = p*1000;
                        if isreal(p)
                            app.LOXLN2densityEditField.Value = p*1000;
                        else
                            app.TextArea.Value = "Error: LN2 is not present in this dataset.";
                            pause(0.01); % This makes the Running box work
                            return
                        end
                        pause(0.1);
                end
            end

            app.TextArea.Value = "Running (8/12)";
            pause(0.01); % This makes the Running box work

            %init end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % %Raw Plots
            % % Tank Load Cells
            % dOTLC1 = (OTLC1(1) - OTLC1(end))/2.205; % kg
            % dOTLC2 = (OTLC2(1) - OTLC2(end))/2.205; % kg
            % dOTLC = dOTLC1 + dOTLC2;
            % dFTLC1 = (FTLC1(1) - FTLC1(end))/2.205; % kg
            % dFTLC2 = (FTLC2(1) - FTLC2(end))/2.205; % kg
            % dFTLC = dFTLC1 + dFTLC2;
            % figure
            % hold on
            % yyaxis left
            % plot(app.times, OTLC1/2.205, 'b-')
            % plot(app.times, OTLC2/2.205, 'b-')
            % plot(app.times, FTLC1/2.205, 'r-')
            % plot(app.times, FTLC2/2.205, 'r-')
            % title('Tank Load Cells')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Mass (kg)')
            % xlabel('Time (s)')
            % yyaxis right
            % plot(app.times, zeros(length(app.times))+dOTLC, 'c-')
            % plot(app.times, zeros(length(app.times))+dFTLC, 'm-')
            % legend('OTLC1', 'OTLC2', 'FTLC1', 'FTLC2', 'dOTLC', 'dFTLC')
            % hold off        
            
            %Data Reduction
            % Valve Timings
            if app.lox~=0  % if oxidizer is flowing
                OIPT_mask = app.OIPT > 10;                                % OIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_OMV] = max(app.OMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with OMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OMV is open
                [~,i_OIPT] = max(OIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the OIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OIPT>10psi
                OMV_open_time = app.times(i_OIPT)-app.times(i_OMV);       % This is the amount of time that OMV is open where oxidizer is travelling to the injector.
                OMV_open_time = round(OMV_open_time, 3);                  % round OMV_open_time to 3 decimal places
                app.OMVTimeTextArea.Value = num2str(OMV_open_time*1000);  % OMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if app.jeta~=0  % if fuel is flowing
                FIPT_mask = app.FIPT > 10;                                % FIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_FMV] = max(app.FMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with FMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FMV is open
                [~,i_FIPT] = max(FIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the FIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FIPT>10psi
                FMV_open_time = app.times(i_FIPT)-app.times(i_FMV);       % This is the amount of time that FMV is open where fuel is travelling to the injector.
                FMV_open_time = round(FMV_open_time, 3);                  % round FMV_open_time to 3 decimal places
                app.FMVTimeTextArea.Value = num2str(FMV_open_time*1000);  % FMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if (app.jeta~=0 && app.lox~=0)  % if both sides are flowing
                loxaccount = app.LOXLeadEditField.Value-(app.FMVTimeEditField.Value-app.OMVTimeEditField.Value);  % There is a deviation from desired Lox Lead in the time it takes for the FMV & OMV to actuate and fluid to travel to their respective injector PTs>10psi
                app.LOXLeadEditField_3.Value = num2str(1000*(FMV_open_time-OMV_open_time)+loxaccount);            % Actual Lox lead is calculated from the lox account and the difference is open times, then it is multiplied to get it in milliseconds and converted into a string to display on the GUI
            end
            
            % Average Mask
            % Mask for Interval where Average is Taken
            if app.jeta~=0
                FMV_mask = app.FMV > 0.5;          % when FMV is open
            else
                FMV_mask = app.OMV > 0.5;          % if FMV is never open or unrreadable, goes to when OMV is open
            end
            if app.CHPT~=0                         % if CHPT is being recorded essentially
                CHPT_mask = app.CHPT > 10;         % CHPT is masked to 0 (<10psi) and 1 (>10psi) for values existing in CHPT
            elseif exist('flpt_index', 'var')==1  
                CHPT_mask = app.FLPT > 10;         % if CHPT isn't being recorded but FLPT exists, CHPT is masked onto FLPT>10psi
            elseif app.lox~=0
                CHPT_mask = app.OIPT > 10;         % if neither work but lox side is flowing, CHPT is masked onto OIPT>10psi
            else                                    
                CHPT_mask = app.FIPT > 10;         % If none of the above work, CHPT is masked to FIPT>10psi
            end
            [~, i_start] = max(CHPT_mask);         % pulls the indexed start time, or the first instance where CHPT_mask hits its maximum (1), which indicates the first time CHPT or masked equivalent > 10psi
            if app.jeta~=0                         % if fuel side is flowing
                [~, i_shift] = max(app.FMV);       % do not need to index FMV or OMV since they are both recorded as valves states (1 & 0 for open & closed). i_shift is recorded as the first time FMV is commanded open
            else
                [~, i_shift] = max(app.OMV);       % if fuel is not flowing, i_shift is recorded as the first time OMV is commanded open
            end
            [~, i_end] = max(app.times(FMV_mask)); % i_end is recorded as the indexed time where FMV_mask is one where FMV will be commanded closed once the fire has ended)
            i_end = i_end + i_shift;               % readjusts the indexed end time to account for the time where FMV was not open
            i = false(length(app.times));          % creates a matrix that has the length and width of the quantity of values recorded for time
            i_start = i_start + round(t_start_avg/dt); % i_start, where FMV opens, and adds the inputted desired time to track before t=0
            i_end = i_end - round(t_end_avg/dt);   % i_end is readjusted to add the time after end of hotfire (after shut off) that vales still need to be recorded (t_end_avg/dt rounded to nearest interget)
            i(i_start:i_end) = true;               % !!!!!!!!!!!!!I actually don't know lol

            app.times;

            app.tzero = find(app.times>0);
            app.tend = find(app.times>5);
            if isempty(app.tzero)
                app.TextArea.Value = "Error: Incorrect input fluids for dataset.";
                pause(0.01); % This makes the Running box work
                return
            end
            app.tzero=app.tzero(1);
            app.tend=app.tzero+(10*sampling_rate); %%%%%%%%%%%%%%%%%%%%% <-- FIX!
            
            app.TextArea.Value = "Running (9/12)";
            pause(0.01); % This makes the Running box work

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % % % %LOX Side
            if app.lox~=0
                % LOX Mdot from Orifice CdA
                app.lox_mdot_cda = app.lox_orifice_cda./100^2.*sqrt(2.*lox_density.*abs(app.OIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (OIPT & CHPT)
    
                % LOX Mdot from System CdA
                app.lox_mdot_sys_cda = app.lox_sys_cda./100^2.*sqrt(2.*lox_density.*abs(app.OTPT-app.OIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (OTPT to OIPT)
    
                % LOX Flow Duration
                OMV_mask = app.OMV > 0.5;                                                 % interval masked when OMV is open (OMV is 1 for open > 0.5)
                omv_flow_duration = max(app.times(OMV_mask)) - min(app.times(OMV_mask));  % calculates the time that OMV is open from first to last instance
                app.omvflowdurTextArea.Value = num2str(omv_flow_duration);                % convert to string then displays on GUI
    
                % LOX Venturi
                inlet_ID = 0.844; % in
                throat_ID = 0.4375; % in
                app.lox_mdot_venturi = app.venturi_cd*pi*(inlet_ID/39.37/2)^2 * sqrt( 2*lox_density*abs(app.ODP)*6895 / ( (( pi*(inlet_ID/39.37/2)^2) / (pi*(throat_ID/39.37/2)^2)) ^2-1)); % kg/s, Bernoulli's Principle
    
                % Average Mdots
                app.avg_lox_mdot_orifice = mean(app.lox_mdot_cda(i));     % kg/s, averaged over all values of i
                app.avg_lox_mdot_sys_cda = mean(app.lox_mdot_sys_cda(i)); % kg/s, averaged over all values of i
                app.avg_lox_mdot_venturi = mean(app.lox_mdot_venturi(i)); % kg/s, averaged over all values of i

                app.avgloxmdotorfTextArea.Value = num2str(app.avg_lox_mdot_orifice); % converts all to strings and displays them on the GUI
                app.avgloxmdotsysTextArea.Value = num2str(app.avg_lox_mdot_sys_cda);
                app.avgloxmdotventuriTextArea.Value = num2str(app.avg_lox_mdot_venturi);
                %
                % System CdA
                app.lox_sys_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2, calculated System CdA using the Mdot from the venturi and the pressure drop across the system
                app.lox_sys_cda_orifice = app.lox_mdot_cda./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2, calculated System CdA using the Mdot from the injector and the pressure drop across the system
    
                % Average System CdA
                app.avg_lox_sys_cda_orifice = mean(app.lox_sys_cda_orifice(i)); % cm^2, averaged over all values of i
                app.avg_lox_sys_cda_venturi = mean(app.lox_sys_cda_venturi(i)); % cm^2, averaged over all values of i
    
                %avg_lox_sys_cda_lc = mean(lox_sys_cda_lc(i)) % cm^2
                
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.lox_orf_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from venturi CdA instead of Mdot from Injector CdA
                app.lox_orf_cda_sys_cda = app.lox_mdot_sys_cda./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from system CdA instead of Mdot from Injector CdA
                app.avg_lox_orf_cda_venturi = mean(app.lox_orf_cda_venturi(i)); % cm^2, averaged over all values of i
                app.avg_lox_orf_cda_sys_cda = mean(app.lox_orf_cda_sys_cda(i)); % cm^2, averaged over all values of i

                app.lox_mdot_cda = app.lox_orf_cda_sys_cda./100^2.*sqrt(2.*lox_density.*abs(app.OIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (OIPT & CHPT)

                app.AvgLOXSysCdAOrificeEditField.Value = num2str(app.avg_lox_sys_cda_orifice);   % converts all to strings and displays them on the GUI
                app.AvgLOXSysCdAVenturiEditField.Value = num2str(app.avg_lox_sys_cda_venturi);
                app.AvgLOXOrfCdASysCdAEditField.Value = num2str(app.avg_lox_orf_cda_venturi);
                app.AvgLOXOrfCdAVenturiEditField.Value = num2str(app.avg_lox_orf_cda_sys_cda);
                % % 
            end
            
            app.TextArea.Value = "Running (10/12)";
            pause(0.01); % This makes the Running box work

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % Fuel Side
            if app.jeta~=0 % if a fluid is flowing on the fuel side, app.jeta~=0 (jeta does not equal 0)
                % Mdot from Orifce CdA
                app.fuel_mdot_cda = app.fuel_orifice_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (FIPT & CHPT)
    
                % Mdot from Orifce CdA
                app.fuel_mdot_sys_cda = app.fuel_sys_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FTPT-app.FIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (FTPT & FIPT)
                
                % Fuel Flow Meter
                app.fuel_mdot_fm = app.FLOW.*fuel_density/1000;  % kg/s, pulls values from app.FLOW and converts it to
                app.fuel_mdot_fm = app.fuel_mdot_fm .* app.fm_multiplier; % kg/s, multiplies the documented fuel_mdot_fm by the flow meter multiplier to get true fuel_mdot_fm
                if app.FlowMeterremovedCheckBox.Value==false %don't calculate fmavg if there are no fm values
                    if app.FlowMeterScalingCorrectionCheckBox.Value == true
                        app.fuel_mdot_fm = app.fuel_mdot_fm * 0.00266667; %% correction factor for fm as 1k PT instead of 0.111-2.208
                    end
                    fmavg(app.fuel_mdot_fm>0.2) = true; % only takes into account values above 0.2 kg/s for averaging into fmavg
                    if ~any(fmavg) % if no values above 0.2 kg/s exist than fmavg errors out
                        app.TextArea.Value = "Error: Fuel is not present in this dataset.";
                        pause(0.01);
                        return
                    end
                    %[~, j_first] = max(j);
                    %[~, i_first] = max(i);
                    %fm_lag = j_first-i_first;
                    %j_last = j_first + (length(i) - fm_lag);
                    %j(j_last:end) = false;
                    fmavg(1:round(end/2))=false;
                    fmavg(round((2/3)*end):end)=false;
                end
                
                % Fuel Flow Duration
                FMV_mask = app.FMV > 0.5;                                                    % interval masked when OMV is open (OMV is 1 for open > 0.5)
                fmv_flow_duration = max(app.times(FMV_mask)) - min(app.times(FMV_mask));     % calculates the time that OMV is open from first to last instance
                app.fmvflowdurTextArea.Value = num2str(fmv_flow_duration);                   % convert to string then displays on GUI
    
                % Average Fuel Mdots
                app.avg_fuel_mdot_orifice = mean(app.fuel_mdot_cda(i));     % kg/s, averaged over all values in i
                app.avg_fuel_mdot_sys_cda = mean(app.fuel_mdot_sys_cda(i)); % kg/s, averaged over all values in i
                if app.FlowMeterremovedCheckBox.Value==false
                    app.avg_fuel_mdot_fm = mean(app.fuel_mdot_fm(fmavg));       % kg/s, averaged over all values in i
                end

                app.avgfuelmdotorfTextArea.Value = num2str(app.avg_fuel_mdot_orifice); % converts values into strings and displays on the GUI
                app.avgfuelmdotsysTextArea.Value = num2str(app.avg_fuel_mdot_sys_cda);
                if app.FlowMeterremovedCheckBox.Value==false
                    app.avgfuelmdotfmTextArea.Value = num2str(app.avg_fuel_mdot_fm);
                end %%% here

                % System CdA
                if app.FlowMeterremovedCheckBox.Value==false
                    app.fuel_sys_cda_fm = app.fuel_mdot_fm./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2, calculates System CdA using Mdot from the Fuel Turbine Flow Meter and dP across the System
                end
                app.fuel_sys_cda_orifice = app.fuel_mdot_cda./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2, calculates System CdA using Mdot from the Injector and dP across the System

                % Average System CdA
                app.avg_fuel_sys_cda_orifice = mean(app.fuel_sys_cda_orifice(i)); % cm^2, averaged over all values in i
                if app.FlowMeterremovedCheckBox.Value==false
                    app.avg_fuel_sys_cda_fm = mean(app.fuel_sys_cda_fm(fmavg)); % cm^2, averaged over all values in i
                end

                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                if app.FlowMeterremovedCheckBox.Value==false
                    app.fuel_orf_cda_fm = app.fuel_mdot_fm./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2, calculates Injector CdA with Mdot from fuel flow meter and dP across Injector
                end
                    app.fuel_orf_cda_sys_cda = app.fuel_mdot_sys_cda./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2, calculates Injector CdA with Mdot from the System using System CdA and dP across Injector
                if app.FlowMeterremovedCheckBox.Value==false
                    app.avg_fuel_orf_cda_fm = mean(app.fuel_orf_cda_fm(fmavg));       % cm^2, averaged over all values in fmavg
                end
                app.avg_fuel_orf_cda_sys_cda = mean(app.fuel_orf_cda_sys_cda(i)); % cm^2, averaged over all values in i

                app.AvgFuelSysCdAOrificeEditField.Value = num2str(app.avg_fuel_sys_cda_orifice);  % converts values into strings and displays on the GUI
                if app.FlowMeterremovedCheckBox.Value==false
                    app.AvgFuelSysCdAFlowMeterEditField.Value = num2str(app.avg_fuel_sys_cda_fm);
                end %%% here
                app.AvgFuelOrfCdASysCdAEditField.Value = num2str(app.avg_fuel_orf_cda_sys_cda);
                if app.FlowMeterremovedCheckBox.Value==false
                    app.AvgFuelOrfCdAFlowMeterEditField.Value = num2str(app.avg_fuel_orf_cda_fm);
                end %%% here
                % % 
            end

            app.TextArea.Value = "Running (11/12)";
            pause(0.01); % This makes the Running box work
            % 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.CheckBox.Value && app.lox==2
                gamma = 1.4;
                A = 0.021/1549.997; %m2 %0.021; %orifice area, in^2 or something. THIS MAY CAUSE A UNIT ERROR.
                cd =0.7;  %refine this
                R = 296.8; %For N2
                stagT = 298; %ambient temp, K
                app.OMV(isnan(app.OMV)) = 0;
                len = sum(app.OMV); %should be length OMV Open
                f_OMV = i_OMV + len; % I changed this bc the other method didn't capture the full fire somehow

                obangvec = app.OBANG(i_OMV:f_OMV-1) >= 1; % Boolean list true if obang open
                
                
                mdot = app.lox_mdot_sys_cda(i_OMV:f_OMV-1); %mdots over relevant time
                
                UllageP = mean(app.OTPT(i_OMV:f_OMV-1)* 6894.76); %Ullage Gas pressure in Pa, Average. OTPT is pretty steady over normal fires
                stagP = app.PRPT2(i_OMV)* 6894.76; %Stag P assumed to be upstream P. Ignores Reg Droop.
  
                mdot_gas_actual = (stagP*A*cd)/(sqrt(stagT))*sqrt(gamma/R) * (((gamma+1)/2)^((gamma+1)/(2*gamma-2)))%uses Choked flow EQ
                               
                open_time = sum(obangvec) * dt; %how long obang is open, total
                delta_M_LOX = sum(mdot*dt); %how much lox we use
                Delta_Vol_ideal = delta_M_LOX/lox_density; % how much volume leaves the tank
                UllageTemp = 200; %deg K, entirely a guess.
                Delta_M_ideal = Delta_Vol_ideal * UllageP/(UllageTemp*(R)) %How much mass of gas would be needed to fill the spent LOX's volume at given P and T
                
                CollapseFactor = (mdot_gas_actual)*open_time/(Delta_M_ideal); %actual mdot over Ideal Mdot
                app.CollapseFactorBetaEditField.Value = CollapseFactor; %sends to app.
                app.OTTC3(1)
            end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.PNPTTextArea.Value = num2str(app.PNPT(1));  % PNPT Value on the GUI is just displayed as the first PNPT value recorded
            
            if app.lox~=0 && app.jeta~=0               % if both sides are flowing
                app.meanCHPT = mean(app.CHPT(i));      % mean CHPT is the average CHPT over the entire interval i
            else
                app.meanCHPT = 0;
            end
            if app.lox~=0                              % if lox side is flowing
                app.meanOIPT = mean(app.OIPT(i));      % mean OIPT is the average OIPT over the entire interval i
            end
            if app.jeta~=0                             % if fuel side is flowing
                app.meanFIPT = mean(app.FIPT(i));      % mean FIPT is the average FIPT over the entire interval i
            end

            if (app.lox==2 && app.jeta==2)             % if KeroLOX is firing
                app.avg_THRUST = mean(app.THRUST(i));  % mean Thrust is the average Thrust over the entire interval i
            end
            
            if app.SpecialboxCheckBox.Value==true
                loctimes = app.times;


                %
                locobang = app.OBANG > 0.5;
                locfbang = app.FBANG > 0.5;
                obangfile = [loctimes', locobang];
                fbangfile = [loctimes', locfbang];
                obangname = [app.data_name(1:end-4), '_obang.csv'];
                fbangname = [app.data_name(1:end-4), '_fbang.csv'];
                writematrix(obangfile, obangname);
                writematrix(fbangfile, fbangname);
                % 
                % prptofile = [loctimes', app.PRPTO];
                % prptffile = [loctimes', app.PRPTF];
                % prptoname = [app.data_name(1:end-4), '_prpto.csv'];
                % prptfname = [app.data_name(1:end-4), '_prptf.csv'];
                % writematrix(prptofile, prptoname);
                % writematrix(prptffile, prptfname);
                % 
                otptfile = [loctimes', app.OIPT];
                ftptfile = [loctimes', app.FIPT];
                chptfile = [loctimes', app.CHPT];
                otptname = [app.data_name(1:end-4), '_oipt.csv'];
                ftptname = [app.data_name(1:end-4), '_fipt.csv'];
                chptname = [app.data_name(1:end-4), '_chpt.csv'];
                writematrix(otptfile, otptname);
                writematrix(ftptfile, ftptname);
                writematrix(chptfile, chptname);
                % 
                % otbtcfile = [loctimes', app.OTBTC];
                % otbtcname = [app.data_name(1:end-4), '_otbtc.csv'];
                % writematrix(otbtcfile, otbtcname);
            end

            if app.TextArea.Value{1}~='E'
                app.TextArea.Value = "Running (12/12)";
                pause(0.01); % This makes the Running box work
    
                app.TextArea.Value = "Finished!";
                pause(5);
                app.TextArea.Value = "Not running.";
            end

            app.JacksonMR = app.lox_mdot_venturi ./ app.fuel_mdot_fm;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        function LoadVespulaData(app, data)
            app.TextArea.Value = "Processing (5/5), Please Stand By...";
            pause(0.01); % This makes the Running box work

            if data(1:13)==['Loaded file: ']                                 % Loaded file display
                %testfile = fullfile(app.filepath, app.file);
                if app.DatainfolderonlySp25VespulaRockettestsCheckBox.Value
                    app.data_name = app.file;
                    if data(end-3:end)==['.mat']
                        app.TextArea.Value = "Error: Use a file from a folder full of CSVs";
                        pause(0.1);
                        return
                    elseif data(end-3:end)==['.csv']
                        thisfilepath = app.filepath;
                        datafiles = dir(fullfile(thisfilepath, '*.csv'));
                        thisdata = readcell([datafiles(1).folder, '\', datafiles(1).name]); %this might be windows-specific
                        bigmatrix = [thisdata(1, :)];
                        for i = 1:length(datafiles)
                            thisdata = readcell([datafiles(i).folder, '\', datafiles(i).name]);
                            bigmatrix = [bigmatrix; thisdata(2:end, :)];
                    end
                        save(datafiles(1).name(1:end-4), "bigmatrix")
                        data = bigmatrix;
                    else
                        app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                        pause(0.1);
                        return
                    end
                else
                    data = fullfile(app.filepath, app.file);
                    app.data_name = app.file;
                    if data(end-3:end)==['.mat']
                        data = importdata(data);
                    elseif data(end-3:end)==['.csv']
                        data = readcell(data);
                    else
                        app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                        pause(0.1);
                        return
                    end
                end
            else
                app.TextArea.Value = "Error: Select a file with the Load File button"; % if imported data is NOT in .mat or .csv, will not process.
                pause(0.1);
                return
            end
            
            app.TextArea.Value = "Running (1/12)";
            
            pause(0.01); % This makes the Running box work
             %Inputs, all TextArea values, directly entered/loaded into the GUI
            % LOX
            app.lox_orifice_cda = app.loxorfcdaTextArea.Value;               % cm^2, inputted injector CDA lox side
            app.lox_sys_cda = app.loxsyscdaTextArea.Value;                   % cm^2, inputted system CDA lox sie
            app.venturi_cd = app.venturicdTextArea.Value;                    % inputted venturi coefficient of discharge lox side
            
            % Fuel
            app.fuel_orifice_cda = app.fuelorfcdaTextArea.Value;             % cm^2, inputted injector CDA fuel side
            app.fuel_sys_cda = app.fuelsyscdaTextArea.Value;                 % cm^2, inputted system CDA fuel side
            
            % LOX Density, defines the density of the oxidizer based on what lox we use
            switch app.lox
                case 2
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, add cool prop, (LOX)
                case 1
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, (LN2)
            end
            
            % Fuel Density
            switch app.jeta
                case 2
                    fuel_density = 800 % kg/m^3, (Kerosene (Jet-A or RP-1))
                case 1
                    fuel_density = 1000 % kg/m^3, (Water)
            end
            
            % Engine throat area
            app.throat_area = 0.00456; %m^2, V regen %%0.00390296138 for V Heatsink; % m^2,      Vespula throat area
            ideal_c_star = 1650; % m/s
            
            app.TextArea.Value = "Running (2/12)";
            pause(0.01); % This makes the Running box work

            % Interval
            %t_before_omv = app.TEditField.Value;           % s, time before OMV opens to plot data
            t_start_avg = 1.5;                              % s, T+ time to pull average
            t_end_avg = 0.1;                                % s, time before OMV closes to pull average
            t_before_fmv = app.TEditField.Value;            % s, time before fmv opens to plot data
            t_plus = app.TEditField_2.Value;                % s, T+ time when to stop plotting
            
            %Index Sensors and Valves
            columns = size(data,2);
            time_index = 1;
            
            % Initialize sensors index to prevent error when sensor is missing, since these sensors are not ALWAYS mapped/cared about during testing.
            % Vehicle PTs, no ground PTs
            prpt_index = 1;
            pnpt_index = 1;
            ftpt_index = 1;
            otpt_index = 1;
            oipt_index = 1;
            fipt_index = 1;
            chpt_index = 1;
            thrust_index = 1; % not a PT
            
            % Vehicle RTDs, no ground RTDs
            prrtd_index = 1;
            furtd_index = 1;
            ourtd_index = 1;
            ortd_index = 1;
            oirtd_index = 1;
            
            % Ground Valves
            gofill_index = 1; % LOX fill
            govnt2_index = 1; % LOX vent
            griso1_index = 1; % GN2 bottle 1
            griso2_index = 1; % GN2 bottle 2
            griso3_index = 1; % GN2 bottle 3
            griso4_index = 1;
            gprfill_index = 1; % GN2 fill
            gprvnt_index = 1; % GN2 vent
            
            % Vehicle Valves
            
            prqd_index = 1;
            prvnt_index = 1;
            
            pnvnt_index = 1;
            
            fbb_index = 1;
            fswv_index = 1;
            fvnt_index = 1;
            fmv_index = 1;
            
            oqd_index = 1;
            obb_index = 1;
            oswv_index = 1;
            ovnt_index = 1;
            omv_index = 1;
            
            app.TextArea.Value = "Running (3/12)";
            pause(0.01); % This makes the Running box work

            for i = 1:columns
                % Vehicle PTs
            
                if strcmp(data{1,i}, ['PRPT']) | strcmp(data{1,i}, ['PRPT(psi)'])
                    prpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PNPT']) | strcmp(data{1,i}, ['PNPT(psi)'])
                    pnpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTPT']) | strcmp(data{1,i}, ['FTPT(psi)'])
                    ftpt_index = i-1;
                end
                        
                if strcmp(data{1,i}, ['OTPT']) | strcmp(data{1,i}, ['OTPT(psi)'])
                    otpt_index = i-1;
                end  
            
                if strcmp(data{1,i}, ['OIPT']) | strcmp(data{1,i}, ['OIPT(psi)'])
                    oipt_index = i-1;
                end
                        
                if strcmp(data{1,i}, ['FIPT']) | strcmp(data{1,i}, ['FIPT1']) | strcmp(data{1,i}, ['FIPT1(psi)'])
                    fipt_index = i-1;
                end

                if strcmp(data{1,i}, ['FIPT2(psi)'])
                    fipt2_index = i-1;
                end
            
                % ENGINE
            
                if strcmp(data{1,i}, ['CHPT']) | strcmp(data{1,i}, ['CHPT(psi)'])
                    chpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['THRUST']) | strcmp(data{1,i}, ['THRUST(lbf)'])
                    thrust_index = i-1;
                end
               
                % Vehicle RTDs
            
                if strcmp(data{1,i}, ['PRRTD'])
                    prrtd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FURTD'])
                    furtd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OURTD'])
                    ourtd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ORTD'])
                    ortd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OIRTD'])
                    oirtd_index = i-1;
                end
            
                % Ground Valves
            
                if strcmp(data{1,i}, ['GOFILL'])
                    gofill_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GOVNT2'])
                    govnt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GRISO1'])
                    griso1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GRISO2'])
                    griso2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GRISO3'])
                    griso3_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GRISO4'])
                    griso4_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GPRFILL'])
                    gprfill_index = i-1;
                end
            
                if strcmp(data{1,i}, ['GPRVNT'])
                    gprvnt_index = i-1;
                end
                
                % Vehicle Valves
            
                if strcmp(data{1,i}, ['PRQD'])
                    prqd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRVNT'])
                    prvnt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PNVNT'])
                    pnvnt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FBB'])
                    fbb_index = i-1;
                end
                
                if strcmp(data{1,i}, ['FSWV'])
                    fswv_index = i-1;
                end
                        
                if strcmp(data{1,i}, ['FVNT'])
                    fvnt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FMV'])
                    fmv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OQD'])
                    oqd_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OBB'])
                    obb_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OSWV'])
                    oswv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OVNT'])
                    ovnt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OMV'])
                    omv_index = i-1;
                end
            end

            app.TextArea.Value = "Running (4/12) ======="; % –
            pause(0.01); % This makes the Running box work

            % DO NOT TOUCH THIS PART, expect it to take a decent time to run
            % Separates time from other data because its easier to reduce that way
            
            app.times = [data{2:end,time_index}]-data{2,time_index}; % Sets time to start from data start instead of from unix time
            
            pure_data = data(2:end,2:end); % Separated data, removes the first column of times (we already documented it) and removes first row of sensor names (we already indexed the locations of the corresponding values)
            % !!! the importance of this is to make all the entries in pure_data numerical values in order to import them and graph later on

            app.TextArea.Value = "Running (4/12) +======";
            pause(0.01);

            %data formatting, -250 is a null value, consistent with AVI syntax
            pure_data(cellfun(@(x) isa(x, 'missing'), pure_data)) = {'-250.000000'}; % "missing" values for sensors are made -250 (traditional for AVI)
            app.TextArea.Value = "Running (4/12) ++=====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'open'), pure_data)) = {'1.0000000000'}; % "open" states in pure_data are changed to a 1
            app.TextArea.Value = "Running (4/12) +++====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'closed'), pure_data)) = {'0.0000000000'}; % "closed" states in pure_data are changed to a 0
            app.TextArea.Value = "Running (4/12) ++++===";
            pause(0.01);

            function out = ifstrthennum(x)
                x = x{1};
                if ischar(x)
                    x = str2double(x);
                end
                out = x;
            end

            if app.AVINouveauCheckBox.Value
                data2 = arrayfun(@(x) ifstrthennum(x), pure_data, 'UniformOutput', 0);
                print = 'tha reel'
                data2 = cell2mat(data2);
                print = 'WE PRINTING BOIS'
            else
                pure_data(cellfun(@(x) (length(x) < 5), pure_data)) = {'-0.00000000'}; % if the data point has less than 5 sig figs, replaced with 0
                app.TextArea.Value = "Running (4/12) +++++==";
                pause(0.01);
                
                data2 = cellfun(@(x) x(1:5), pure_data,'UniformOutput',false);
                app.TextArea.Value = "Running (4/12) ++++++=";
                pause(0.01);
    
                data2 = str2double(data2); % all strings in data2 are now converted to doubles
            end          

            app.TextArea.Value = "Running (4/12) +++++++";
            pause(0.01);
            app.TextArea.Value = "Running (5/12)";
            pause(0.01); % This makes the Running box work

             %Intervals
            if app.jeta~=0
                local_time = [data2(1:end,fmv_index)]; % if the fuel side is flowing, local_time will be referenced from fmv (recall fmv is on 0/1 for closed/open)
            else
                local_time = [data2(1:end,omv_index)]; % if fuel side is not flowing, local_time will be referenced from omv (recall omv is on 0/1 for closed/open)
            end
            if app.CHPT~=0
                app.CHPT = [data2(1:end,chpt_index)]; % assigns CHPT using recorded CHPT index, have to do this before all other variable because of basing timings off of when CHPT is above ambient (combustion has started)
            elseif app.lox~=0
                app.CHPT = [data2(1:end,oipt_index)];
            else
                app.CHPT = [data2(1:end,fipt_index)];
            end
            
            dt = (app.times(end)-app.times(1))/length(app.times);    % seconds, change in real time from one point to the next point
            app.deltat = dt;
            sampling_rate = 1/dt;                                    % Hz, how many data samples per second we get
            sampling_rate = round(sampling_rate, 3);                 % rounds to 3 decimal places
            app.SamplingRateTextArea.Value = num2str(sampling_rate); % converts sampling rate to a string and dispalys it on the GUI
            [~,data_start] = max(local_time);                        % records the index time of the max of local_time. Matlab itself will record the first instance of 1 if there are multiple instances. Essentially, data_start is recorded as soon as fmv opens.
            data_start = data_start - round(t_before_fmv/dt);        % adjusts data_start time to however long user wants to graph before FMV opens 
            
            data_end = data_start + round(t_before_fmv/dt) + round(t_plus/dt); % uses data_start and wanted to period to track when data should end (data_end)
            [~,t_zero] = max(app.CHPT>10);                                     % similar method as before, app.CHPT>10 takes all of app.CHPT and converts it to 1 for true (>10psi) and 0 for false (<10psi) and takes the first instance of the max(1=true->>10psi) as t_zero. Thus (t_zero) is the first instance where CHPT>10psi which is when combustion begins
            app.times = app.times - app.times(t_zero);                         % !!!!!re-adjusts app.times to reference t_zero as 0 in app.times
            if data_start < 1
                data_start = 1;
            end
            if data_end > length(data2)
                data_end = length(data2);                                      % in case data_end is longer than what the file has to plot, the file will stop plotting once it runs out of data.
            end
            data_start = 1;
            data_end = length(data2);
            data2 = data2(data_start:data_end, :);                             % !!!!!!!!!data2 is formatted from data_start to data_end
            app.times = app.times(data_start:data_end);                        % !!!!!!!!!gets times and formates it from data_start to data_end
            
            app.TextArea.Value = "Running (6/12)";
            pause(0.01); % This makes the Running box work

            % Begin variable assignment
            
            % Pnuematics Data
            app.PNPT = [data2(1:end,pnpt_index)];
            app.PNVNT = [data2(1:end,pnvnt_index)];
            
            % Pressurant Data
            app.PRPT = [data2(1:end,prpt_index)];
            app.PRRTD = [data2(1:end,prrtd_index)];
            app.PRQD = [data2(1:end,prqd_index)];
            app.PRVNT = [data2(1:end,prvnt_index)];
            
            % Ground Valves
            app.GOFILL = [data2(1:end,gofill_index)];
            app.GOVNT2 = [data2(1:end,govnt2_index)];
            app.GRISO1 = [data2(1:end,griso1_index)];
            app.GRISO2 = [data2(1:end,griso2_index)];
            app.GRISO3 = [data2(1:end,griso3_index)];
            app.GRISO4 = [data2(1:end,griso4_index)];
            app.GPRFILL = [data2(1:end,gprfill_index)];
            app.GPRVNT = [data2(1:end,gprvnt_index)];

            % Fuel Data
            if app.jeta ~= 0 % Vehicle Fuel Flow sensors & valves to be tracked
                app.FIPT = [data2(1:end,fipt_index)];
                app.FIPT2 = [data2(1:end, fipt2_index)];
                app.FTPT = [data2(1:end,ftpt_index)];
                app.FMV = [data2(1:end,fmv_index)];
                app.FSWV = [data2(1:end,fswv_index)];
                app.FVNT = [data2(1:end,fvnt_index)];
                app.FBB = [data2(1:end,fbb_index)];
                app.FURTD = [data2(1:end,furtd_index)];
            
                % Tare
                app.FIPT = app.FIPT - app.FIPT(1);
                app.FIPT2 = app.FIPT2 - app.FIPT2(1);
            end
            
            % Oxidizer Data
            if app.lox ~= 0 % Vehicle Oxidizer Flow sensors and & valves to be tracked
                app.OTPT = [data2(1:end,otpt_index)];
                app.OIPT = [data2(1:end,oipt_index)];
                app.OURTD = [data2(1:end,ourtd_index)];
                app.ORTD = [data2(1:end,ortd_index)];
                app.OIRTD = [data2(1:end,oirtd_index)];
                app.OQD = [data2(1:end,oqd_index)];
                app.OMV = [data2(1:end,omv_index)];
                app.OSWV = [data2(1:end,oswv_index)];
                app.OVNT = [data2(1:end,ovnt_index)];
                app.OBB = [data2(1:end,obb_index)];

                % Tare
                app.OIPT = app.OIPT - app.OIPT(1);
            end
            
            if app.jeta ~= 0 && app.lox ~= 0
                app.CHPT = [data2(1:end,chpt_index)];
                app.THRUST = [data2(1:end,thrust_index)];

                % Tare
                app.CHPT = app.CHPT - app.CHPT(1);
                app.THRUST = app.THRUST - app.THRUST(1);
            end

            app.TextArea.Value = "Running (7/12)";
            pause(0.01); % This makes the Running box work
            
            if app.lox == 1
                app.LOXLN2densityEditField.Value = 1100;
            elseif app.lox == 2
                app.LOXLN2densityEditField.Value = 740;
            end

            app.TextArea.Value = "Running (8/12)";
            pause(0.01); % This makes the Running box work

            %init end

            %Data Reduction
            % Valve Timings
            if app.lox~=0  % if oxidizer is flowing
                OIPT_mask = app.OIPT > 10;                                % OIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_OMV] = max(app.OMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with OMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OMV is open
                [~,i_OIPT] = max(OIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the OIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OIPT>10psi
                OMV_open_time = app.times(i_OIPT)-app.times(i_OMV);       % This is the amount of time that OMV is open where oxidizer is travelling to the injector.
                OMV_open_time = round(OMV_open_time, 3);                  % round OMV_open_time to 3 decimal places
                app.OMVTimeTextArea.Value = num2str(OMV_open_time*1000);  % OMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if app.jeta~=0  % if fuel is flowing
                FIPT_mask = app.FIPT > 10;                                % FIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_FMV] = max(app.FMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with FMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FMV is open
                [~,i_FIPT] = max(FIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the FIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FIPT>10psi
                FMV_open_time = app.times(i_FIPT)-app.times(i_FMV);       % This is the amount of time that FMV is open where fuel is travelling to the injector.
                FMV_open_time = round(FMV_open_time, 3);                  % round FMV_open_time to 3 decimal places
                app.FMVTimeTextArea.Value = num2str(FMV_open_time*1000);  % FMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if (app.jeta~=0 && app.lox~=0)  % if both sides are flowing
                loxaccount = app.LOXLeadEditField.Value-(app.FMVTimeEditField.Value-app.OMVTimeEditField.Value);  % There is a deviation from desired Lox Lead in the time it takes for the FMV & OMV to actuate and fluid to travel to their respective injector PTs>10psi
                app.LOXLeadEditField_3.Value = num2str(1000*(FMV_open_time-OMV_open_time)+loxaccount);            % Actual Lox lead is calculated from the lox account and the difference is open times, then it is multiplied to get it in milliseconds and converted into a string to display on the GUI
            end
            
            % Average Mask
            % Mask for Interval where Average is Taken
            if app.jeta~=0
                FMV_mask = app.FMV > 0.5;          % when FMV is open
            else
                FMV_mask = app.OMV > 0.5;          % if FMV is never open or unrreadable, goes to when OMV is open
            end
            if app.CHPT~=0                         % if CHPT is being recorded essentially
                CHPT_mask = app.CHPT > 10;         % CHPT is masked to 0 (<10psi) and 1 (>10psi) for values existing in CHPT
            elseif app.lox~=0
                CHPT_mask = app.OIPT > 10;         % if neither work but lox side is flowing, CHPT is masked onto OIPT>10psi
            else                                    
                CHPT_mask = app.FIPT > 10;         % If none of the above work, CHPT is masked to FIPT>10psi
            end
            
            [~, i_start] = max(CHPT_mask);         % pulls the indexed start time, or the first instance where CHPT_mask hits its maximum (1), which indicates the first time CHPT or masked equivalent > 10psi
            if app.jeta~=0                         % if fuel side is flowing
                [~, i_shift] = max(app.FMV);       % do not need to index FMV or OMV since they are both recorded as valves states (1 & 0 for open & closed). i_shift is recorded as the first time FMV is commanded open
            else
                [~, i_shift] = max(app.OMV);       % if fuel is not flowing, i_shift is recorded as the first time OMV is commanded open
            end
            [~, i_end] = max(app.times(FMV_mask)); % i_end is recorded as the indexed time where FMV_mask is one where FMV will be commanded closed once the fire has ended)
            i_end = i_end + i_shift;               % readjusts the indexed end time to account for the time where FMV was not open
            i = false(length(app.times));          % creates a matrix that has the length and width of the quantity of values recorded for time
            i_start = i_start + round(t_start_avg/dt); % i_start, where FMV opens, and adds the inputted desired time to track before t=0
            i_end = i_end - round(t_end_avg/dt);   % i_end is readjusted to add the time after end of hotfire (after shut off) that vales still need to be recorded (t_end_avg/dt rounded to nearest interget)
            i(i_start:i_end) = true;               % !!!!!!!!!!!!!I actually don't know lol

            app.tzero = find(app.times>0);        
            app.tend = find(app.times>5);          
            if isempty(app.tzero)
                app.TextArea.Value = "Error: Incorrect input fluids for dataset.";
                pause(0.01); % This makes the Running box work
                return
            end
            app.tzero=app.tzero(1);
            app.tend=app.tzero+(5*sampling_rate);
            
            app.TextArea.Value = "Running (9/12)";
            pause(0.01); % This makes the Running box work

            % % % % %LOX Side
            if app.lox~=0
                % LOX Mdot from Orifice CdA
                app.lox_mdot_cda = app.lox_orifice_cda./100^2.*sqrt(2.*lox_density.*abs(app.OIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (OIPT & CHPT)
    
                % LOX Mdot from System CdA
                app.lox_mdot_sys_cda = app.lox_sys_cda./100^2.*sqrt(2.*lox_density.*abs(app.OTPT-app.OIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (OTPT to OIPT)
    
                % LOX Flow Duration
                OMV_mask = app.OMV > 0.5;                                                 % interval masked when OMV is open (OMV is 1 for open > 0.5)
                omv_flow_duration = max(app.times(OMV_mask)) - min(app.times(OMV_mask));  % calculates the time that OMV is open from first to last instance
                app.omvflowdurTextArea.Value = num2str(omv_flow_duration);                % convert to string then displays on GUI
    
                % LOX Venturi
                inlet_ID = 0.844; % in
                throat_ID = 0.4375; % in
    
                % Average Mdots
                app.avg_lox_mdot_orifice = mean(app.lox_mdot_cda(i));     % kg/s, averaged over all values of i
                app.avg_lox_mdot_sys_cda = mean(app.lox_mdot_sys_cda(i)); % kg/s, averaged over all values of i
                %app.avg_lox_mdot_venturi = mean(app.lox_mdot_venturi(i)); % kg/s, averaged over all values of i

                app.avgloxmdotorfTextArea.Value = num2str(app.avg_lox_mdot_orifice); % converts all to strings and displays them on the GUI
                app.avgloxmdotsysTextArea.Value = num2str(app.avg_lox_mdot_sys_cda);
                app.avgloxmdotventuriTextArea.Value = 'N/A';
                %
                % System CdA
                %app.lox_sys_cda_venturi =
                %app.lox_mdot_venturi./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; %cm^2, commented out bc there's no ODP for Vespula
                app.lox_sys_cda_orifice = app.lox_mdot_cda./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2, calculated System CdA using the Mdot from the injector and the pressure drop across the system
    
                % Average System CdA
                app.avg_lox_sys_cda_orifice = mean(app.lox_sys_cda_orifice(i)); % cm^2, averaged over all values of i
                %app.avg_lox_sys_cda_venturi = mean(app.lox_sys_cda_venturi(i)); % cm^2, averaged over all values of i
    
                %avg_lox_sys_cda_lc = mean(lox_sys_cda_lc(i)) % cm^2
                
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                %app.lox_orf_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from venturi CdA instead of Mdot from Injector CdA
                app.lox_orf_cda_sys_cda = app.lox_mdot_sys_cda./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from system CdA instead of Mdot from Injector CdA
                %app.avg_lox_orf_cda_venturi = mean(app.lox_orf_cda_venturi(i)); % cm^2, averaged over all values of i
                app.avg_lox_orf_cda_sys_cda = mean(app.lox_orf_cda_sys_cda(i)); % cm^2, averaged over all values of i

                app.AvgLOXSysCdAOrificeEditField.Value = num2str(app.avg_lox_sys_cda_orifice);   % converts all to strings and displays them on the GUI
                app.AvgLOXSysCdAVenturiEditField.Value = 'N/A'; %num2str(app.avg_lox_sys_cda_venturi);
                app.AvgLOXOrfCdASysCdAEditField.Value = num2str(app.avg_lox_orf_cda_venturi);
                app.AvgLOXOrfCdAVenturiEditField.Value = 'N/A';
                % % 
            end
            
            app.TextArea.Value = "Running (10/12)";
            pause(0.01); % This makes the Running box work

            app.CHPT

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % Fuel Side
            if app.jeta~=0 % if a fluid is flowing on the fuel side, app.jeta~=0 (jeta does not equal 0)
                % Mdot from Orifce CdA
                app.fuel_mdot_cda = app.fuel_orifice_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (FIPT & CHPT)
    
                % Mdot from Orifce CdA
                app.fuel_mdot_sys_cda = app.fuel_sys_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FTPT-app.FIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (FTPT & FIPT)
                
                % Fuel Flow Duration
                FMV_mask = app.FMV > 0.5;                                                    % interval masked when OMV is open (OMV is 1 for open > 0.5)
                fmv_flow_duration = max(app.times(FMV_mask)) - min(app.times(FMV_mask));     % calculates the time that OMV is open from first to last instance
                app.fmvflowdurTextArea.Value = num2str(fmv_flow_duration);                   % convert to string then displays on GUI
    
                % Average Fuel Mdots
                app.avg_fuel_mdot_orifice = mean(app.fuel_mdot_cda(i));     % kg/s, averaged over all values in i
                app.avg_fuel_mdot_sys_cda = mean(app.fuel_mdot_sys_cda(i)); % kg/s, averaged over all values in i

                app.avgfuelmdotorfTextArea.Value = num2str(app.avg_fuel_mdot_orifice); % converts values into strings and displays on the GUI
                app.avgfuelmdotsysTextArea.Value = num2str(app.avg_fuel_mdot_sys_cda);
                app.avgfuelmdotfmTextArea.Value = 'N/A';

                % System CdA
                app.fuel_sys_cda_orifice = app.fuel_mdot_cda./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2, calculates System CdA using Mdot from the Injector and dP across the System

                % Average System CdA
                app.avg_fuel_sys_cda_orifice = mean(app.fuel_sys_cda_orifice(i)); % cm^2, averaged over all values in i
    
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.fuel_orf_cda_fm = 'N/A';
                app.fuel_orf_cda_sys_cda = app.fuel_mdot_sys_cda./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2, calculates Injector CdA with Mdot from the System using System CdA and dP across Injector
                app.avg_fuel_orf_cda_fm = 'N/A';
                app.avg_fuel_orf_cda_sys_cda = mean(app.fuel_orf_cda_sys_cda(i)); % cm^2, averaged over all values in i

                app.AvgFuelSysCdAOrificeEditField.Value = num2str(app.avg_fuel_sys_cda_orifice);  % converts values into strings and displays on the GUI
                app.AvgFuelSysCdAFlowMeterEditField.Value = 'N/A';
                app.AvgFuelOrfCdASysCdAEditField.Value = num2str(app.avg_fuel_orf_cda_sys_cda);
                app.AvgFuelOrfCdAFlowMeterEditField.Value = 'N/A';
                % % 
            end

            app.TextArea.Value = "Running (11/12)";
            pause(0.01); % This makes the Running box work

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.CheckBox.Value && app.lox~=0
                gamma = 1.4;
                A = 5.06707E-6; %m2 %0.021; %orifice area, in^2 or something. THIS MAY CAUSE A UNIT ERROR.
                cd= 0.64;  %refine this
                R = 296.8; %For N2
                stagT = 298; %ambient temp, K
                app.OMV(isnan(app.OMV)) = 0;
                len = sum(app.OMV); %should be length OMV Open
                f_OMV = i_OMV + len % I changed this bc the other method didn't capture the full fire somehow

                obangvec = app.OBB(i_OMV:f_OMV-1) >= 1; % Boolean list true if obang open
                
                
                mdot = app.lox_mdot_sys_cda(i_OMV:f_OMV-1); %mdots over relevant time
                
                UllageP = mean(app.OTPT(i_OMV:f_OMV-1)* 6894.76); %Ullage Gas pressure in Pa, Average. OTPT is pretty steady over normal fires
                stagP = app.PRPT(i_OMV)* 6894.76; %Stag P assumed to be upstream P. Ignores Reg Droop.
  
                mdot_gas_actual = (stagP*A*cd)/(sqrt(stagT))*sqrt(gamma/R) * (((gamma+1)/2)^((gamma+1)/(2*gamma-2)))%uses Choked flow EQ
                               
                open_time = sum(obangvec) * dt; %how long obang is open, total
                delta_M_LOX = sum(mdot*dt); %how much lox we use
                Delta_Vol_ideal = delta_M_LOX/lox_density; % how much volume leaves the tank
                UllageTemp = 200; %deg K, entirely a guess.
                Delta_M_ideal = Delta_Vol_ideal * UllageP/(UllageTemp*(R)) %How much mass of gas would be needed to fill the spent LOX's volume at given P and T
                
                CollapseFactor = (mdot_gas_actual)*open_time/(Delta_M_ideal); %actual mdot over Ideal Mdot
                app.CollapseFactorBetaEditField.Value = CollapseFactor; %sends to app.
            end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            app.PNPTTextArea.Value = num2str(app.PNPT(1));  % PNPT Value on the GUI is just displayed as the first PNPT value recorded
            
            if app.lox~=0 && app.jeta~=0               % if both sides are flowing
                app.meanCHPT = mean(app.CHPT(i));      % mean CHPT is the average CHPT over the entire interval i
            else
                app.meanCHPT = 0;
            end
            if app.lox~=0                              % if lox side is flowing
                app.meanOIPT = mean(app.OIPT(i));      % mean OIPT is the average OIPT over the entire interval i
            end
            if app.jeta~=0                             % if fuel side is flowing
                app.meanFIPT = mean(app.FIPT(i));      % mean FIPT is the average FIPT over the entire interval i
            end

            if (app.lox==2 && app.jeta==2)             % if KeroLOX is firing
                app.avg_THRUST = mean(app.THRUST(i));  % mean Thrust is the average Thrust over the entire interval i
            end

            if app.TextArea.Value{1}~='E'
                app.TextArea.Value = "Running (12/12)";
                pause(0.01); % This makes the Running box work
    
                app.TextArea.Value = "Finished!";
                pause(5);
                app.TextArea.Value = "Not running.";
            end
        end
    end

    methods (Access = private)

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StahlDataAnalysisUIFigure.Name = ['DANGUI ' app.versionLabel.Text];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.EngineClassDropDown.Value(end-3:end) == 'HETS'
                thisfolder = mfilename("fullpath");
                if ~strcmp(thisfolder(end-16:end), 'StahlDataAnalysis')
                    app.TextArea.Value = "Error: Don't change the filename >:(";
                    pause(0.01); % This makes the Running box work
                    return
                end
                thisfolder(end-17:end) = [];
                cd(thisfolder)
                
                app.TextArea.Value = "Processing (1/5)";
                pause(0.01); % This makes the Running box work
    
                if strcmp(app.DatasetInputLabel.Text, '<no input selected>')
                    app.TextArea.Value = "Error: No dataset input selected.";
                    pause(0.01); % This makes the Running box work
                    return
                end
    
                app.OMVTimeTextArea.Value = '--';
                app.FMVTimeTextArea.Value = '--';
                app.LOXLeadEditField_3.Value = '--';
                app.PNPTTextArea.Value = '--';
                app.omvflowdurTextArea.Value = '--';
                app.fmvflowdurTextArea.Value = '--';
                app.SamplingRateTextArea.Value = '--';
    
                app.avgloxmdotorfTextArea.Value = '--';
                app.avgloxmdotsysTextArea.Value = '--';
                app.avgloxmdotventuriTextArea.Value = '--';
                app.avgfuelmdotorfTextArea.Value = '--';
                app.avgfuelmdotsysTextArea.Value = '--';
                app.avgfuelmdotfmTextArea.Value = '--';
                app.AvgLOXSysCdAOrificeEditField.Value = '--';
                app.AvgLOXSysCdAVenturiEditField.Value = '--';
                app.AvgLOXOrfCdASysCdAEditField.Value = '--';
                app.AvgLOXOrfCdAVenturiEditField.Value = '--';
                app.AvgFuelSysCdAOrificeEditField.Value = '--';
                app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
                app.AvgFuelOrfCdASysCdAEditField.Value = '--';
                app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';
    
                app.TextArea.Value = "Processing (2/5)";
                pause(0.01); % This makes the Running box work
                
                if app.LOXCheckBox.Value == true
                    app.lox = 2;
                elseif app.LN2CheckBox.Value == true
                    app.lox = 1;
                else 
                    app.lox = 0;
                end
    
                app.TextArea.Value = "Processing (3/5)";
                pause(0.01); % This makes the Running box work
    
                if app.JetACheckBox.Value == true
                    app.jeta = 2;
                elseif app.WaterCheckBox.Value == true
                    app.jeta = 1;
                else
                    app.jeta = 0;
                end
                
                app.TextArea.Value = "Processing (4/5)";
                pause(0.01); % This makes the Running box work
    
                LoadNewData(app, app.datacsvEditField.Value);
            else
                % Vespula loading stuff starts here
                thisfolder = mfilename("fullpath");
                if ~strcmp(thisfolder(end-16:end), 'StahlDataAnalysis')
                    app.TextArea.Value = "Error: Don't change the filename >:(";
                    pause(0.01); % This makes the Running box work
                    return
                end
                thisfolder(end-17:end) = [];
                cd(thisfolder)
                
                app.TextArea.Value = "Processing (1/5)";
                pause(0.01); % This makes the Running box work
    
                if strcmp(app.DatasetInputLabel.Text, '<no input selected>')
                    app.TextArea.Value = "Error: No dataset input selected.";
                    pause(0.01); % This makes the Running box work
                    return
                end
    
                app.OMVTimeTextArea.Value = '--';
                app.FMVTimeTextArea.Value = '--';
                app.LOXLeadEditField_3.Value = '--';
                app.PNPTTextArea.Value = '--';
                app.omvflowdurTextArea.Value = '--';
                app.fmvflowdurTextArea.Value = '--';
                app.SamplingRateTextArea.Value = '--';
    
                app.avgloxmdotorfTextArea.Value = '--';
                app.avgloxmdotsysTextArea.Value = '--';
                app.avgloxmdotventuriTextArea.Value = '--';
                app.avgfuelmdotorfTextArea.Value = '--';
                app.avgfuelmdotsysTextArea.Value = '--';
                app.avgfuelmdotfmTextArea.Value = '--';
                app.AvgLOXSysCdAOrificeEditField.Value = '--';
                app.AvgLOXSysCdAVenturiEditField.Value = '--';
                app.AvgLOXOrfCdASysCdAEditField.Value = '--';
                app.AvgLOXOrfCdAVenturiEditField.Value = '--';
                app.AvgFuelSysCdAOrificeEditField.Value = '--';
                app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
                app.AvgFuelOrfCdASysCdAEditField.Value = '--';
                app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';
    
                app.TextArea.Value = "Processing (2/5)";
                pause(0.01); % This makes the Running box work
                
                if app.LOXCheckBox.Value == true
                    app.lox = 2;
                elseif app.LN2CheckBox.Value == true
                    app.lox = 1;
                else 
                    app.lox = 0;
                end
    
                app.TextArea.Value = "Processing (3/5)";
                pause(0.01); % This makes the Running box work
    
                if app.JetACheckBox.Value == true
                    app.jeta = 2;
                elseif app.WaterCheckBox.Value == true
                    app.jeta = 1;
                else
                    app.jeta = 0;
                end
                
                app.TextArea.Value = "Processing (4/5)";
                pause(0.01); % This makes the Running box work
    
                LoadVespulaData(app, app.datacsvEditField.Value);
            end
        end

        % Value changed function: LOXCheckBox
        function LOXCheckBoxValueChanged(app, event)
            value = app.LOXCheckBox.Value;
            if value == true
                app.LN2CheckBox.Value = false;
                app.LOXLN2densityEditFieldLabel.Text = 'LOX Density';
                app.LOXLN2densityEditField.Value = 1141;
            end
        end

        % Value changed function: LN2CheckBox
        function LN2CheckBoxValueChanged(app, event)
            value = app.LN2CheckBox.Value;
            if value == true
                app.LOXCheckBox.Value = false;
                app.LOXLN2densityEditFieldLabel.Text = 'LN2 Density';
                app.LOXLN2densityEditField.Value = 807;
            end
        end

        % Value changed function: JetACheckBox
        function JetACheckBoxValueChanged(app, event)
            value = app.JetACheckBox.Value;
            if value == true
                app.WaterCheckBox.Value = false;
            end
        end

        % Value changed function: WaterCheckBox
        function WaterCheckBoxValueChanged(app, event)
            value = app.WaterCheckBox.Value;
            if value == true
                app.JetACheckBox.Value = false;
            end
        end

        % Button pushed function: PurgeButton
        function PurgeButtonPushed(app, event)
            % Purge
            figure
            hold on
            plot(app.times, app.PUPT2)
            title('Purge Pressure')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psig)')
            xlabel('Time (s)')
            hold off
        end

        % Button pushed function: PressureLaddersButton
        function PressureLaddersButtonPushed(app, event)
            % % Gasses plot
            if app.PRPTOCheckBox.Value==true || app.PRPTFCheckBox.Value==true
                legendcell = [];                                                    % resets legend
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.PRPTOCheckBox.Value==true)
                    plot(app.times, app.PRPTO, 'r-')
                    legendcell = [legendcell, {'PRPTO'}];
                end
                if (app.PRPTFCheckBox.Value==true)
                    plot(app.times, app.PRPTF, 'm-')
                    legendcell = [legendcell, {'PRPTF'}];
                end
                ylabel('Pressure (psig)')
                title('Gas Pressures')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
            end

            % % %LOX Side
            % % Full Pressure Ladder Plot
            if app.lox~=0 && (app.OTPTCheckBox.Value || app.ORUNPTCheckBox.Value || app.OIPTCheckBox.Value)
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.OTPTCheckBox.Value==true)
                    otptplot = plot(app.times, app.OTPT, 'c-');
                    plot(app.times, zeros(length(app.OTPT),1)+app.OTPTTargetEditField.Value, 'c--');
                    %[a,b] = max(app.OTPT(app.tzero+10:app.tend));
                    %[c,d] = min(app.OTPT(app.tzero+10+b:app.tzero+10+b+50));
                    %b = app.tzero+10+b;
                    %d = b+d;
                    %datatip(otptplot, app.times(b), a, Location='northwest')
                    %datatip(otptplot, app.times(d), c, Location='northeast')
                    legendcell = [legendcell, {'OTPT'}, {'OTPT Target'}];
                end
                if (app.ORUNPTCheckBox.Value==true)
                    if app.SwitchORUNandFRUNCheckBox.Value==false
                        orunptplot = plot(app.times, app.ORUNPT, 'm-');
                        %datatip(orunptplot, app.times(b), app.ORUNPT(b), Location='southeast')
                        legendcell = [legendcell, {'ORUNPT'}];
                    else
                        plot(app.times, app.FRUNPT, 'm-')
                        legendcell = [legendcell, {'ORUNPT'}];
                    end
                end
                if (app.OIPTCheckBox.Value==true)
                    oiptplot = plot(app.times, app.OIPT, 'r-');
                    %datatip(oiptplot, app.times(b), app.OIPT(b), Location='southwest')
                    plot(app.times, zeros(length(app.OIPT),1)+app.OIPTTargetEditField.Value, 'r--');
                    legendcell = [legendcell, {'OIPT'}, {'OIPT Target'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    chptplot = plot(app.times, app.CHPT, 'b-');
                    % datatip(chptplot, app.times(b), app.CHPT(b), Location='southeast')
                    plot(app.times, zeros(length(app.CHPT),1)+app.CHPTTargetEditField.Value, 'b--');
                    legendcell = [legendcell, {'CHPT'}, {'CHPT Target'}];
                end
                ylabel('Pressure (psig)')
                title('LOX Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
    
                % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.OTPT, 'c-')
                % plot(app.times, app.ORUNPT, 'm-')
                % plot(app.times, app.OIPT, 'r-')
                % %plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('LOX Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('OTPT', 'ORUNPT', 'OIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end

            % Fuel Side
            % Full Pressure Ladder Plot
            if app.jeta~=0 && (app.FTPTCheckBox.Value || app.FRUNPTCheckBox.Value || app.FIPTCheckBox.Value || app.FINPTCheckBox.Value || app.FLPTCheckBox.Value)
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.FTPTCheckBox.Value==true)
                    ftptplot = plot(app.times, app.FTPT, 'c-');
                    plot(app.times, zeros(length(app.FTPT),1)+app.FTPTTargetEditField.Value, 'c--');
                    % [a,b] = max(app.FTPT(app.tzero+10:app.tend));
                    % [c,d] = min(app.FTPT(app.tzero+10+b:app.tzero+10+b+50));
                    % b = app.tzero+10+b;
                    % d = b+d;
                    % datatip(ftptplot, app.times(b), a, Location='northwest')
                    % datatip(ftptplot, app.times(d), c, Location='northeast')
                    legendcell = [legendcell, {'FTPT'}, {'FTPT Target'}];
                end
                if (app.FRUNPTCheckBox.Value==true)
                    if app.SwitchORUNandFRUNCheckBox.Value==false
                        frunptplot = plot(app.times, app.FRUNPT, 'm-');
                        % datatip(frunptplot, app.times(b), app.FRUNPT(b), Location='southeast')
                        legendcell = [legendcell, {'FRUNPT'}];
                    else
                        plot(app.times, app.ORUNPT, 'm-')
                        legendcell = [legendcell, {'FRUNPT'}];
                    end
                end
                if (app.FIPTCheckBox.Value==true)
                    fiptplot = plot(app.times, app.FIPT, 'r-');
                    % datatip(fiptplot, app.times(b), app.FIPT(b), Location='southwest')
                    plot(app.times, zeros(length(app.FIPT),1)+app.FIPTTargetEditField.Value, 'r--');
                    legendcell = [legendcell, {'FIPT'}, {'FIPT Target'}];
                end
                if (app.FLPTCheckBox.Value==true && app.FLPT~=0)
                    plot(app.times, app.FLPT, 'r.')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if (app.FINPTCheckBox.Value==true && app.FINPT~=0)
                    plot(app.times, app.FINPT, 'r--')
                    legendcell = [legendcell, {'FINPT'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    chptplot = plot(app.times, app.CHPT, 'b-');
                    % datatip(chptplot, app.times(b), app.CHPT(b), Location='southeast')
                    plot(app.times, zeros(length(app.CHPT),1)+app.CHPTTargetEditField.Value, 'b--');
                    legendcell = [legendcell, {'CHPT'}, {'CHPT Target'}];
                end
                ylabel('Pressure (psig)')
                title('Fuel Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
                % 
                % % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.FTPT, 'c-')
                % plot(app.times, app.FRUNPT, 'm-')
                % plot(app.times, app.FIPT, 'r-')
                % %plot(app.times, FLPT, 'r.')
                % %plot(app.times, FINPT, 'r--')
                % plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('Fuel Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('FTPT', 'FRUNPT', 'FIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end
            if ((app.jeta ~= 0 || app.lox ~= 0) && app.CHPTCheckBox.Value==true && ...
                    ~(app.FTPTCheckBox.Value || app.FRUNPTCheckBox.Value || app.FIPTCheckBox.Value || app.FINPTCheckBox.Value || app.FLPTCheckBox.Value) && ...
                    ~(app.OTPTCheckBox.Value || app.ORUNPTCheckBox.Value || app.OIPTCheckBox.Value))
                % Plot CHPT
                figure
                plot(app.times, app.CHPT)
                title('CHPT')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('CHPT')
            end
            if app.PNPTCheckBox.Value==true
                % % Plot PNPT
                figure
                hold on
                plot(app.times, app.PNPT)
                plot(app.times, app.PNPT2)
                title('PNPT')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('PNPT', 'PNPT2')
                hold off
            end
            if app.FLOWCheckBox.Value==true
                % % Plot FM
                figure
                hold on
                plot(app.times, app.FLOW)
                title('Flow Meter DP')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Flow Meter')
                hold off
            end
            if app.ODPCheckBox.Value==true
                % % Plot ODP
                figure
                hold on
                plot(app.times, app.ODP)
                title('Venturi ODP')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('ODP')
                hold off
            end
        end

        % Button pushed function: mdotsButton
        function mdotsButtonPushed(app, event)
            if app.lox~=0
                % % LOX Mdot Plot
                figure
                hold on
                plot(app.times, app.lox_mdot_cda, '-b');
                plot(app.times, app.lox_mdot_sys_cda, '-c');
                plot(app.times, app.lox_mdot_venturi, '-r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetLOXmdotEditField.Value, 'k.');
                mdotorfplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotorfTextArea.Value), 'b--');
                mdotsysplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotsysTextArea.Value), 'c--');
                mdotventuriplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotventuriTextArea.Value), 'r--');
                if app.ShowdatatipsCheckBox.Value==true
                    datatip(mdotorfplot, app.times(end), str2double(app.avgloxmdotorfTextArea.Value), Location='northwest')
                    datatip(mdotsysplot, app.times(end), str2double(app.avgloxmdotsysTextArea.Value), Location='southwest')
                    datatip(mdotventuriplot, app.times(end), str2double(app.avgloxmdotventuriTextArea.Value), Location='southwest')
                end
                title('LOX Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                ylim([0 3])
                legend('Injector CdA', 'System CdA', 'Venturi')
                
                if (app.ShowinputsCheckBox_2.Value==true)
                    ooricda = app.lox_orifice_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice CdA = ', ooricda];
                    ooricda(25) = ' ';
    
                    ooridp = app.meanOIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(25) = ' ';
    
                    osyscda = app.lox_sys_cda;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['System CdA = ', osyscda];
                    osyscda(25) = ' ';
    
                    osysdp = mean(app.OTPT)-app.meanOIPT;
                    osysdp = num2str(round(osysdp, 3));
                    osysdp = ['System dP = ' osysdp];
                    osysdp(25) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(25) = ' ';
    
                    annotation('textbox', [0.15, 0.3, 0.1, 0.1], 'String', [ooricda; ooridp; osyscda; osysdp; loxrho])
                end
                hold off
            end

            if app.jeta~=0
                % % Fuel Mdot Plot
                figure
                hold on
                plot(app.times, app.fuel_mdot_cda, '-b');
                plot(app.times, app.fuel_mdot_sys_cda, '-c');
                if app.FlowMeterremovedCheckBox.Value==false
                    plot(app.times, app.fuel_mdot_fm, '-r');
                end
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetFuelmdotEditField.Value, 'k.');
                mdotorfplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotorfTextArea.Value), '--b');
                mdotsysplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotsysTextArea.Value), '--c');
                if app.FlowMeterremovedCheckBox.Value==false
                    mdotfmplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotfmTextArea.Value), '--r');
                end
                if app.ShowdatatipsCheckBox.Value==true
                    datatip(mdotorfplot, app.times(end), str2double(app.avgfuelmdotorfTextArea.Value), Location='northwest')
                    datatip(mdotsysplot, app.times(end), str2double(app.avgfuelmdotsysTextArea.Value), Location='southwest')
                    if app.FlowMeterremovedCheckBox.Value==false
                        datatip(mdotfmplot, app.times(end), str2double(app.avgfuelmdotfmTextArea.Value), Location='southwest')
                    end
                end
                title('Fuel Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                if app.FlowMeterremovedCheckBox.Value==false
                    legend('Injector CdA', 'System CdA', 'Flow Meter')
                else
                    legend('Injector CdA', 'System CdA')
                end
                if (app.ShowinputsCheckBox_2.Value==true)
                    foricda = app.fuel_orifice_cda;
                    foricda = num2str(round(foricda, 3));
                    foricda = ['Orifice CdA = ', foricda];
                    foricda(20) = ' ';
    
                    foridp = app.meanFIPT-app.meanCHPT;
                    foridp = num2str(round(foridp, 3));
                    foridp = ['Orifice dP = ' foridp];
                    foridp(20) = ' ';
    
                    fsyscda = app.fuel_sys_cda;
                    fsyscda = num2str(round(fsyscda, 3));
                    fsyscda = ['System CdA = ', fsyscda];
                    fsyscda(20) = ' ';
    
                    fsysdp = mean(app.FTPT)-app.meanFIPT;
                    fsysdp = num2str(round(fsysdp, 3));
                    fsysdp = ['System dP = ' fsysdp];
                    fsysdp(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [foricda; foridp; fsyscda; fsysdp])
                end
                hold off
            end
        end

        % Button pushed function: CdAsButton
        function CdAsButtonPushed(app, event)
            if app.lox~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.lox_sys_cda_orifice, '-b')
                plot(app.times, app.lox_sys_cda_venturi, '-r')
                cdaorfplot = plot(app.times, zeros(length(app.lox_sys_cda_orifice),1)+str2double(app.AvgLOXSysCdAOrificeEditField.Value), '--b');
                cdaventuriplot = plot(app.times, zeros(length(app.lox_sys_cda_venturi),1)+str2double(app.AvgLOXSysCdAVenturiEditField.Value), '--r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.LOXSystemCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdaorfplot, app.times(end), str2double(app.AvgLOXSysCdAOrificeEditField.Value), Location='northwest')
                    datatip(cdaventuriplot, app.times(end), str2double(app.AvgLOXSysCdAVenturiEditField.Value), Location='southwest')
                end
                title('LOX System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', 'Venturi', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.lox_sys_cda*1.5])
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_lox_mdot_orifice;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot = ', ooricda];
                    ooricda(25) = ' ';
    
                    osyscda = app.avg_lox_mdot_venturi;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['Venturi mdot = ', osyscda];
                    osyscda(25) = ' ';
    
                    osysdp = app.meanOIPT-app.meanCHPT;
                    osysdp = num2str(round(osysdp, 3));
                    osysdp = ['System dP = ' osysdp];
                    osysdp(25) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(25) = ' ';
    
                    annotation('textbox', [0.15, 0.25, 0.1, 0.1], 'String', [ooricda; osyscda; osysdp; loxrho])
                end
                hold off

                % Plot Injector CdA
                OIPT_mask = app.OIPT > 10;
                figure
                hold on
                thesetimes = app.times(OIPT_mask);
                plot(thesetimes, app.lox_orf_cda_sys_cda(OIPT_mask), '-c');
                plot(thesetimes, app.lox_orf_cda_venturi(OIPT_mask), '-r');
                cdainjplot = plot(thesetimes, zeros(length(app.lox_orf_cda_venturi(OIPT_mask)),1)+app.lox_orifice_cda, '--g');
                cdasysplot = plot(thesetimes, zeros(length(app.lox_orf_cda_sys_cda(OIPT_mask)),1)+str2double(app.AvgLOXOrfCdASysCdAEditField.Value), '--c');
                cdaventuriplot = plot(thesetimes, zeros(length(app.lox_orf_cda_venturi(OIPT_mask)),1)+str2double(app.AvgLOXOrfCdAVenturiEditField.Value), '--r');
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.LOXInjectorCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdasysplot, thesetimes(end), str2double(app.AvgLOXOrfCdASysCdAEditField.Value), Location='northwest')
                    datatip(cdaventuriplot, thesetimes(end), str2double(app.AvgLOXOrfCdAVenturiEditField.Value), Location='southwest')
                    datatip(cdainjplot, thesetimes(end), app.lox_orifice_cda, Location='southeast')
                end
                title('LOX Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('System CdA', 'Venturi', 'Injector CdA', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                %ylim([0, app.lox_orifice_cda*1.5])
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_lox_mdot_sys_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot w/ System CdA = ', ooricda];
                    ooricda(35) = ' ';
    
                    ooridp = app.meanOIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(35) = ' ';
    
                    osyscda = app.avg_lox_mdot_venturi;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['Venturi mdot = ', osyscda];
                    osyscda(35) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(35) = ' ';
    
                    annotation('textbox', [0.15, 0.25, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp; loxrho])
                end
                hold off
            end

            if app.jeta~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.fuel_sys_cda_orifice, '-b');
                if app.FlowMeterremovedCheckBox.Value==false
                    plot(app.times, app.fuel_sys_cda_fm, '-r');
                end
                cdaorfplot = plot(app.times, zeros(length(app.fuel_sys_cda_orifice),1)+str2double(app.AvgFuelSysCdAOrificeEditField.Value), '--b');
                if app.FlowMeterremovedCheckBox.Value==false
                    cdafmplot = plot(app.times, zeros(length(app.fuel_sys_cda_fm),1)+str2double(app.AvgFuelSysCdAFlowMeterEditField.Value), '--r');
                end
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.FuelSystemCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdaorfplot, app.times(end), str2double(app.AvgFuelSysCdAOrificeEditField.Value), Location='northwest')
                    if app.FlowMeterremovedCheckBox.Value==false
                        datatip(cdafmplot, app.times(end), str2double(app.AvgFuelSysCdAFlowMeterEditField.Value), Location='southwest')
                    end
                end
                title('Fuel System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                if app.FlowMeterremovedCheckBox.Value==false
                    legend('Injector', 'Flow Meter', Location='northwest')
                else
                    legend('Injector', Location='northwest')
                end
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_fuel_mdot_orifice;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot = ', ooricda];
                    ooricda(20) = ' ';
    
                    ooridp = mean(app.FTPT)-app.meanFIPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['System dP = ' ooridp];
                    ooridp(20) = ' ';
    
                    osyscda = app.avg_fuel_mdot_fm;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['FM mdot = ', osyscda];
                    osyscda(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp])
                end
                hold off

                % Plot Injector CdA
                FIPT_mask = app.FIPT > 10;
                figure
                hold on
                thesetimes = app.times(FIPT_mask);
                plot(thesetimes, app.fuel_orf_cda_sys_cda(FIPT_mask), '-c');
                if app.FlowMeterremovedCheckBox.Value==false
                    plot(thesetimes, app.fuel_orf_cda_fm(FIPT_mask), '-r');
                end
                cdainjplot = plot(thesetimes, zeros(sum(FIPT_mask))+app.fuel_orifice_cda, '--g');
                cdasysplot = plot(thesetimes, zeros(sum(FIPT_mask))+str2double(app.AvgFuelOrfCdASysCdAEditField.Value), '--c');
                if app.FlowMeterremovedCheckBox.Value==false
                    cdafmplot = plot(thesetimes, zeros(length(app.fuel_orf_cda_fm(FIPT_mask)),1)+str2double(app.AvgFuelOrfCdAFlowMeterEditField.Value), '--r');
                end
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.FuelInjectorCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdasysplot, thesetimes(end), str2double(app.AvgFuelOrfCdASysCdAEditField.Value), Location='northwest')
                    if app.FlowMeterremovedCheckBox.Value==false
                        datatip(cdafmplot, thesetimes(end), str2double(app.AvgFuelOrfCdAFlowMeterEditField.Value), Location='southwest')
                    end
                    datatip(cdainjplot, thesetimes(end), app.fuel_orifice_cda, Location='southeast')
                end
                title('Fuel Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.fuel_orifice_cda*1.5])
                if app.FlowMeterremovedCheckBox.Value==false
                    legend('System CdA', 'Flow Meter', 'Injector CdA', Location='northwest')
                else
                    legend('System CdA', 'Injector CdA', Location='northwest')
                end
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_fuel_mdot_sys_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['System mdot = ', ooricda];
                    ooricda(20) = ' ';
    
                    ooridp = app.meanFIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(20) = ' ';
    
                    osyscda = app.avg_fuel_mdot_fm;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['FM mdot = ', osyscda];
                    osyscda(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp])
                end
                hold off
            end
        end

        % Button pushed function: ValvesButton
        function ValvesButtonPushed(app, event)
            % Sense Wire Break Time
            SENSE_mask = app.SENSE > 4;
            
            legendcell = [];

            % % Plot Valves / Igniters
            figure
            hold on
            ylabel('Valve State')
            yyaxis left
            if app.lox~=0
                plot(app.times, app.OIPT, 'c-')
                legendcell = [legendcell, {'OIPT'}];
            end
            if app.jeta~=0
                exist('finpt_index', 'var')
                plot(app.times, app.FIPT, 'm-')
                legendcell = [legendcell, {'FIPT'}];
                if app.FLPT~=0
                    plot(app.times, app.FLPT, 'm--')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if app.FINPT~=0
                    plot(app.times, app.FINPT, 'm-.')
                    legendcell = [legendcell, {'FINPT'}];
                end
            end
            if app.WithPressuresCheckBox.Value==true
                if app.lox~=0
                    plot(app.times(1:20:end), app.OTPT(1:20:end), 'c-square')
                    legendcell = [legendcell, {'OTPT'}];
                end
                if app.jeta~=0
                    plot(app.times(1:20:end), app.FTPT(1:20:end), 'm-square')
                    legendcell = [legendcell, {'FTPT'}];
                end
                plot(app.times, app.PNPT, 'b--')
                legendcell = [legendcell, {'PNPT'}];
            end
            ylabel('Pressure (psig)')
            yyaxis right
            if app.lox~=0
                plot(app.times, app.OMV, 'b-x')
                legendcell = [legendcell, {'OMV'}];
            end
            if app.jeta~=0
                plot(app.times, app.FMV, 'r-o')
                legendcell = [legendcell, {'FMV'}];
            end
            if app.lox~=0
                plot(app.times, app.PUO, 'g-')
                legendcell = [legendcell, {'PUO'}];
            end
            if app.jeta~=0
                plot(app.times, app.PUF,'y-')
                legendcell = [legendcell, {'PUF'}];
            end
            if app.lox==2 && app.jeta==2
                plot(app.times, app.IGNITER1, 'r--')
                plot(app.times, app.IGNITER2,'m--')
                plot(app.times, SENSE_mask,'g--')
                legendcell = [legendcell, {'IGNITER1'}, {'IGNITER2'}, {'SENSE'}];
            end
            title('Valve Timings')
            subtitle(app.data_name, 'Interpreter', 'none')
            xlabel('Time (s)')
            legend(legendcell)
            hold off
        end

        % Button pushed function: ThrustButton
        function ThrustButtonPushed(app, event)
            % % Thrust
            THRUST_zero = mean(app.THRUST(1:round(1/app.deltat)));
            app.THRUST = abs(app.THRUST-THRUST_zero);
            figure
            hold on
            yyaxis left
            thrustplot = plot(app.times, app.THRUST, 'b-');
            avgthrustplot = plot(app.times, zeros(length(app.THRUST),1)+abs(app.avg_THRUST), 'b--');
            plot(app.times, zeros(length(app.THRUST),1)+THRUST_zero, 'g--')
            %[a,b] = max(app.THRUST(app.tzero:app.tend));
            %datatip(thrustplot, app.times(app.tzero+b), a)
            %datatip(avgthrustplot, app.times(1), -app.avg_THRUST)
            title('Thrust')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Load (lbf)')
            xlabel('Time (s)')
            yyaxis right
            plot(app.times, app.CHPT, 'r-')
            ylabel('Pressure (psig)')
            legend('Tared Thrust', 'Avg Thrust', 'Tare', 'CHPT')
            hold off

            % Thrust Coefficient
            thrust_coefficient = app.THRUST.*4.448./(app.CHPT.*6895.*app.throat_area);  % formula for thrust coefficient

            % % Thrust Coefficient Plot
            figure
            hold on
            yyaxis left
            plot(app.times, app.THRUST, 'r')
            ylabel('Thrust (lbf)')
            yyaxis right
            plot(app.times, thrust_coefficient, 'c--')
            ylim([0 2])
            ylabel('Thrust Coefficient')
            xlabel('Time (s)')
            title('Thrust Coefficient')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Thrust', 'Thrust Coefficient')
            hold off

            % Isp
            app.ISP = (app.THRUST.*4.448)./((app.fuel_mdot_sys_cda + app.lox_mdot_venturi)*9.81);                      % formula for Specific Impulse using best mdots as of 2/2/25
            app.ISPavg_values = (app.THRUST.*4.448)./((app.avg_fuel_mdot_sys_cda + app.avg_lox_mdot_venturi)*9.81);    % formula for Specific Impulse using best average mdots as of 2/2/25

            % Isp Plots
            figure
            hold on 
            yyaxis left
            plot(app.times, app.ISP, 'r')
            plot(app.times, app.ISPavg_values, 'g')
            ylabel('Isp (m/s)')
            xlabel('Time (s)')
            title('Specific Impulse')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Isp', 'Isp avg values')
            hold off
        end

        % Button pushed function: EngineDataButton
        function EngineDataButtonPushed(app, event)
            %Engine
            % Select Mdot Measurement Method
            lox_mdot = app.lox_mdot_cda;
            fuel_mdot_flomtr = app.fuel_mdot_fm; %named weird bc app tries to reference different variable
            fuel_mdot_syscda = app.fuel_mdot_sys_cda; %same
            fuel_mdot_injcda = app.fuel_mdot_cda;
            if app.FlowMeterremovedCheckBox.Value==false
                MRfm = lox_mdot./fuel_mdot_flomtr;
                MRfm(1:app.tzero) = 0;
                MRfm(app.tend:end) = 0;
            end
            MRsys = lox_mdot./fuel_mdot_syscda;
            MRinj = lox_mdot./fuel_mdot_injcda;
            MRsys(1:app.tzero) = 0;
            MRsys(app.tend:end) = 0;
            MRinj(1:app.tzero) = 0;
            MRinj(app.tend:end) = 0;
            
            if app.FlowMeterremovedCheckBox.Value==false
                % Everything Engine Plot fm
                figure
                hold on
                yyaxis left
                ylabel('Pressure (psig)')
                plot(app.times, app.CHPT, 'g-')
                plot(app.times, app.OIPT, 'c-')
                plot(app.times, app.FIPT, 'm-')
                yyaxis right
                ylabel('Mass Flow (kg/s)')
                plot(app.times, lox_mdot, 'b-')
                plot(app.times, fuel_mdot_flomtr, 'r-')
                plot(app.times, MRfm, '-')
                ylim([0 3])
                legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
                xlabel('Time (s)')
                title('Engine Data - Flow Meter')
                subtitle(app.data_name, 'Interpreter', 'none')
                hold off
            end

            % Everything Engine Plot sys cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_syscda, 'r-')
            plot(app.times, MRsys, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - System CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off

            % Everything Engine Plot inj cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_injcda, 'r-')
            plot(app.times, MRinj, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - Injector CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off
        end

        % Button pushed function: TemperaturesButton
        function TemperaturesButtonPushed(app, event)
            % Plot LOX Temps
             figure
             hold on
             plot(app.times, app.OTBTC)
             %plot(app.times, app.OTUTC)
             plot(app.times, app.OFMTC)
             %plot(app.times, app.OTTC1)
             plot(app.times, app.OTTC2)
             plot(app.times, app.OTTC3)
             plot(app.times, app.OITC)
             title('LOX Temperatures')
             subtitle(app.data_name, 'Interpreter', 'none')
             ylabel('Temperature (K)')
             xlabel('Time (s)')
             legend('OTBTC','OFMTC', 'OTTC1', 'OTTC2', 'OTTC3', 'OITC')
             hold off
            
            % % Plot Engine Temps
            if 1~=1
                figure
                hold on
                plot(app.times, app.HFTC1)
                plot(app.times, app.HFTC2)
                title('Heat Flux Temperatures')
                subtitle(app.data_name, 'Interpreter', 'none')
                ylabel('Temperature (K)')
                xlabel('Time (s)')
                legend('HFTC1','HFTC2')
                hold off
            end

            % % Plot Engine Temps
            if 1~=1
                figure
                hold on
                plot(app.times, app.ETC1)
                plot(app.times, app.ETC3)
                title('Engine Temperatures')
                subtitle(app.data_name, 'Interpreter', 'none')
                ylabel('Temperature (K)')
                xlabel('Time (s)')
                legend('ETC1','ETC3')
                hold off
            end

            % % Plot Fuel Temps
            % figure
            % hold on
            % plot(app.times, app.FTUTC)
            % title('Fuel Temperatures')
            % subtitle(app.data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('FTUTC')
            % hold off
            % 
            % % Plot Engine Temps
            % figure
            % hold on
            % plot(app.times, app.ETC1)
            % plot(app.times, app.ETC2)
            % plot(app.times, app.ETC3)
            % % plot(app.times, ETC4)
            % title('Engine Temperatures')
            % subtitle(app.data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('ETC1', 'ETC2', 'ETC3', 'ETC4')
            % hold off  
        end

        % Button pushed function: CEfficiencyButton
        function CEfficiencyButtonPushed(app, event)
            % Calculates Theoretical C* 
            
            % Dario's Tool, if this doesn't work then use basic
            % calculation 
            if app.jeta == 2
                fuel = "Jet-A(L)";
            end
            if app.lox == 2
                oxidizer = "O2(L)";
            end
            
            cd NASA_CEA_Package
            
            % Assigns file name used for the CEA toolF
            fileName = fullfile("" + fuel + "_" + oxidizer + "_ro");
            txtFileName = fullfile("input.txt");

            % Writes name of file in input.txt. to be used as input for the
            % run.bat file when called later
            writematrix(fileName, txtFileName);

            Pc = app.meanCHPT+14.7;
            pressureUnit = "psia";

            avg_MR_lox_venturi_fuel_sys = (app.avg_lox_mdot_venturi/app.avg_fuel_mdot_sys_cda);
            ceaInput = ["problem";"ro";"fac";"ac/at="+6;"p,"+pressureUnit+"="+Pc;"supar= 3.2";"o/f="+avg_MR_lox_venturi_fuel_sys;"reactant";"fu "+fuel+" wt%=100";"ox "+oxidizer+" wt%=100";"output short";"output siunits";"output massf";"output transport";"plot t gam m cf";"end"];
            writematrix(ceaInput,fileName+'.txt','QuoteStrings',0);
            % need to convert the .txt file to a . inp since writematrix
            % would not work with .inp
            % 4 lines below are from the mathworks forum:
            % https://www.mathworks.com
            txtFile = fileName + '.txt';
            inpFile = strrep(txtFile,'.txt','.inp');
            copyfile(txtFile,inpFile);
            delete(fileName+'.txt');

            % run CEA Solver
            system('"run.bat"');

            % Collect results
            CEAResults = readmatrix(fileName+".csv");
            
            cd ..

            %CEAResults(row, col)
            %row 2 is combustion end
            %column 1 is Temp
            %Column 2 is gamma
            %Column 3 is molecular weight
            T = CEAResults(2,1); %K
            gamma = CEAResults(2,2);
            mw = CEAResults(2,3);
            R = 8314.5/mw;
            
            Cstar_theo = (sqrt(gamma*T*R))/(gamma*sqrt((2/(gamma+1))^((gamma+1)/(gamma-1)))); % Theoretical C* for an ideally expanded engine

            % Saaketh Ideal C* idea (in case CEA pull doesn't work)
            % Cstar_theo = ((6895.*app.CHPTTargetEditField.Value).*(app.throat_area))./(app.TargetFuelmdotEditField.Value + app.TargetLOXmdotEditField.Value);
 

            % Calculates Actual C*
            Cstar_act = (((app.CHPT+14.7)*6895).*(app.throat_area))./(app.fuel_mdot_sys_cda + app.lox_mdot_venturi);

            % Plots C* Efficiency
            Cstar_efficiency = ((Cstar_act)./(Cstar_theo)).*100;

            figure
            hold on
            plot(app.times,Cstar_efficiency)
            title('C-Star Efficiency')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Efficiency (%)')
            xlabel('Time (s)')
            legend('C* Efficiency')
            hold off
        end

        % Button pushed function: defunctButton
        function defunctButtonPushed(app, event)
            % PurgePlot(app);
            % PressuresPlots(app);
            % MDotPlots(app);
            % CdAsPlots(app);
            % ValvesPlot(app);
            % ThrustPlots(app);
            % EngineDataPlot(app);
            % TemperaturePlots(app);
            % CstarEfficiencyPlot(app);
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loxorfcdaTextArea.Value = app.LOXInjectorCdAEditField.Value;
            app.loxsyscdaTextArea.Value = app.LOXSystemCdAEditField.Value;
            app.fuelorfcdaTextArea.Value = app.FuelInjectorCdAEditField.Value;
            app.fuelsyscdaTextArea.Value = app.FuelSystemCdAEditField.Value;

            app.OTPTTargetEditField.Value = app.OTPTTargetEditField_2.Value;
            app.FTPTTargetEditField.Value = app.FTPTTargetEditField_2.Value;
            app.OIPTTargetEditField.Value = app.OIPTTargetEditField_2.Value;
            app.FIPTTargetEditField.Value = app.FIPTTargetEditField_2.Value;
            app.CHPTTargetEditField.Value = app.CHPTTargetEditField_2.Value;
            app.TargetLOXmdotEditField.Value = app.TargetLOXmdotEditField_2.Value;
            app.TargetFuelmdotEditField.Value = app.TargetFuelmdotEditField_2.Value;

            app.LOXLeadEditField.Value = app.LOXLeadEditField_4.Value;
            app.OMVTimeEditField.Value = app.OMVTimeEditField_2.Value;
            app.FMVTimeEditField.Value = app.FMVTimeEditField_2.Value;

            app.DatasetInputLabel.Text = app.DatasetDropDown.Value;
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            % Complete:
            % 02.22.25 SS HS Heat Flux 2
            % 02.22.25 Vespula Heatsink 3.5
            % 02.01.25 Vespula Heatscrub
            % 11.23.24 Subscale Heatsink Heat Flux
            % 11.10.24 Vespula Main Valves Fire
            % 11.09.24 Vespula Flows New Sequence
            % 10.19.24 Subscale Ablative V2.5
            % 10.19.24 Vespula Heatsink
            % 10.06.24 Subscale Ablative
            % 10.05.24 Vespula Flows
            % 09.28.24 Vespula Flows
            % 09.21.24 Subscale Coax Remachined
            % 09.15.24 Cold Flows
            % 04.28.24 Regen Ramp Up
            % 04.28.24 Ablative Fire
            % 04.27.24 Pintle Ramp Up
            % 04.20.24 Subscale Coax 5
            % 04.20.24 Subscale Coax 4
            % 04.13.24 Coax Ramp Up
            % Next:
            % 04.07.24 Subscale Pintle 1
            % 04.07.24 Subscale Coax 3
            % 03.31.24 Subscale Coax 2
            % 03.30.24 Subscale Coax 1
            
            if strcmp(value, '10.17.25 Vespula Flows')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 1.889;
                app.FuelInjectorCdAEditField.Value = 0.5625;
                app.FuelSystemCdAEditField.Value = 0.8313;

                app.OTPTTargetEditField_2.Value = 361;
                app.FTPTTargetEditField_2.Value = 539;
                app.OIPTTargetEditField_2.Value = 333;
                app.FIPTTargetEditField_2.Value = 322;
                app.CHPTTargetEditField_2.Value = 235;
                app.TargetLOXmdotEditField_2.Value = 2.95;
                app.TargetFuelmdotEditField_2.Value = 1.51;

                app.LOXLeadEditField_4.Value = 10;
                app.OMVTimeEditField_2.Value = 125;
                app.FMVTimeEditField_2.Value = 80;

                app.EngineClassDropDown.Value = 'Vespula - Rocket';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = true;
                app.FlowMeterScalingCorrectionCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end

            if strcmp(value, '10.11.25 Vespula Ablative 2')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 379;
                app.FTPTTargetEditField_2.Value = 581;
                app.OIPTTargetEditField_2.Value = 321;
                app.FIPTTargetEditField_2.Value = 352;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 150; %% these might be wrong lol D: %% I hope they weren't
                app.OMVTimeEditField_2.Value = 135;
                app.FMVTimeEditField_2.Value = 160;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = true;
                app.AVINouveauCheckBox.Value = true;
                app.FlowMeterScalingCorrectionCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '09.27.25 Regen Full')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.5625;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 380;
                app.FTPTTargetEditField_2.Value = 650;
                app.OIPTTargetEditField_2.Value = 320;
                app.FIPTTargetEditField_2.Value = 350;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 2.95;
                app.TargetFuelmdotEditField_2.Value = 1.51;

                app.LOXLeadEditField_4.Value = 170;
                app.OMVTimeEditField_2.Value = 135;
                app.FMVTimeEditField_2.Value = 240;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = true;
                app.FlowMeterScalingCorrectionCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '09.21.25 Regen Blip')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.5625;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 380;
                app.FTPTTargetEditField_2.Value = 650;
                app.OIPTTargetEditField_2.Value = 320;
                app.FIPTTargetEditField_2.Value = 350;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 2.95;
                app.TargetFuelmdotEditField_2.Value = 1.51;

                app.LOXLeadEditField_4.Value = 170;
                app.OMVTimeEditField_2.Value = 135;
                app.FMVTimeEditField_2.Value = 240;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = true;
                app.FlowMeterScalingCorrectionCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '09.20.25 Regen Fuel Flows')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 0;
                app.FTPTTargetEditField_2.Value = 650;
                app.OIPTTargetEditField_2.Value = 0;
                app.FIPTTargetEditField_2.Value = 0;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.1;
                app.TargetFuelmdotEditField_2.Value = 1.51;

                app.LOXLeadEditField_4.Value = 150;
                app.OMVTimeEditField_2.Value = 135;
                app.FMVTimeEditField_2.Value = 160;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = true;
                app.FlowMeterScalingCorrectionCheckBox.Value = true;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '09.13.25 Vespula Ablative')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 381;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 150; %% these might be wrong lol D:
                app.OMVTimeEditField_2.Value = 135;
                app.FMVTimeEditField_2.Value = 160;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = true;
                app.AVINouveauCheckBox.Value = true;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '09.07.25 HETS Activation Flows')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 391;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 20; %% these might be wrong lol D:
                app.OMVTimeEditField_2.Value = 400;
                app.FMVTimeEditField_2.Value = 600;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = true;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '04.12.25 V. Heatsink Double Feature')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.85;
                app.FuelInjectorCdAEditField.Value = 0.260;
                app.FuelSystemCdAEditField.Value = 0.300;

                app.OTPTTargetEditField_2.Value = 354;
                app.FTPTTargetEditField_2.Value = 334;
                app.OIPTTargetEditField_2.Value = 342;
                app.FIPTTargetEditField_2.Value = 301;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 50;
                app.OMVTimeEditField_2.Value = 430;
                app.FMVTimeEditField_2.Value = 380;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = true;
                app.AVINouveauCheckBox.Value = true;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, 'what jackson thinks vespula inputs are')
                app.LOXInjectorCdAEditField.Value = 0.865;
                app.LOXSystemCdAEditField.Value = 0.908;
                app.FuelInjectorCdAEditField.Value = 0.482;
                app.FuelSystemCdAEditField.Value = 0.5553;

                app.OTPTTargetEditField_2.Value = 400;
                app.FTPTTargetEditField_2.Value = 400;
                app.OIPTTargetEditField_2.Value = 350;
                app.FIPTTargetEditField_2.Value = 350;
                app.CHPTTargetEditField_2.Value = 300;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 100;
                app.OMVTimeEditField_2.Value = 130;
                app.FMVTimeEditField_2.Value = 120;

                app.EngineClassDropDown.Value = 'Vespula - Rocket';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Note: these came from the VespulaFlowsAnalysis.m script and are wrong';
            end
            if strcmp(value, '02.22.25 SS HS Heat Flux 2')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.85;
                app.FuelInjectorCdAEditField.Value = 0.260;
                app.FuelSystemCdAEditField.Value = 0.300;

                app.OTPTTargetEditField_2.Value = 354;
                app.FTPTTargetEditField_2.Value = 334;
                app.OIPTTargetEditField_2.Value = 342;
                app.FIPTTargetEditField_2.Value = 301;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '02.22.25 Vespula Heatsink 3.5')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 391;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '02.01.25 Vespula Heatscrub')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 391;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 100;
                app.OMVTimeEditField_2.Value = 130;
                app.FMVTimeEditField_2.Value = 120;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '11.24.24 Subscale Heatsink Heat Flux')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.260;
                app.FuelSystemCdAEditField.Value = 0.341;

                app.OTPTTargetEditField_2.Value = 354;
                app.FTPTTargetEditField_2.Value = 334;
                app.OIPTTargetEditField_2.Value = 342;
                app.FIPTTargetEditField_2.Value = 301;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '11.16.24 Vespula Main Valves Fire')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.973;
                app.FuelInjectorCdAEditField.Value = 0.5553;
                app.FuelSystemCdAEditField.Value = 0.344;

                app.OTPTTargetEditField_2.Value = 450;
                app.FTPTTargetEditField_2.Value = 610;
                app.OIPTTargetEditField_2.Value = 366;
                app.FIPTTargetEditField_2.Value = 362;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 3.24;
                app.TargetFuelmdotEditField_2.Value = 1.62;

                app.LOXLeadEditField_4.Value = 100;
                app.OMVTimeEditField_2.Value = 130;
                app.FMVTimeEditField_2.Value = 120;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'11.16.24 Vespula Flows New Sequence')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.973;
                app.FuelInjectorCdAEditField.Value = 0.507;
                app.FuelSystemCdAEditField.Value = 0.344;

                app.OTPTTargetEditField_2.Value = 214;
                app.FTPTTargetEditField_2.Value = 277;
                app.OIPTTargetEditField_2.Value = 114;
                app.FIPTTargetEditField_2.Value = 77;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.24;
                app.TargetFuelmdotEditField_2.Value = 1.62;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 0;

                app.NotesLabel.Text = 'Note: Expected valve timings don''t matter for flows.';
            end
            if strcmp(value,'10.19.24 Subscale Ablative V2.5')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.2300;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 340;
                app.OIPTTargetEditField_2.Value = 314;
                app.FIPTTargetEditField_2.Value = 285;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.01;
                app.TargetFuelmdotEditField_2.Value = 0.56;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'10.19.24 Vespula Heatsink')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.5553;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 421;
                app.FTPTTargetEditField_2.Value = 477;
                app.OIPTTargetEditField_2.Value = 334;
                app.FIPTTargetEditField_2.Value = 376;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.NotesLabel.Text = 'Note: ORUNPT and FRUNPT are switched.';
                app.SwitchORUNandFRUNCheckBox.Value = true;
            end
            if strcmp(value,'10.06.24 Subscale Ablative')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 297;
                app.OIPTTargetEditField_2.Value = 314;
                app.FIPTTargetEditField_2.Value = 285;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.01;
                app.TargetFuelmdotEditField_2.Value = 0.56;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'10.05.24 Vespula Flows')
                app.LOXInjectorCdAEditField.Value = 1.1;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 1.26;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 141;
                app.FTPTTargetEditField_2.Value = 115;
                app.OIPTTargetEditField_2.Value = 54;
                app.FIPTTargetEditField_2.Value = 15;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Note: Fuel and LN2 are in separate files.';
            end
            if strcmp(value,'09.28.24 Vespula Flows')
                app.LOXInjectorCdAEditField.Value = 0.504;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.304333;
                app.FuelSystemCdAEditField.Value = 0.475;

                app.OTPTTargetEditField_2.Value = 485;
                app.FTPTTargetEditField_2.Value = 356;
                app.OIPTTargetEditField_2.Value = 362;
                app.FIPTTargetEditField_2.Value = 252;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.EngineClassDropDown.Value = 'Vespula - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.NotesLabel.Text = 'Note: Fuel and LN2 are in separate files.';
            end
            if strcmp(value,'09.21.24 Subscale Coax Remachined')
                app.LOXInjectorCdAEditField.Value = 0.41;
                app.LOXSystemCdAEditField.Value = 1.004;
                app.FuelInjectorCdAEditField.Value = 0.32;
                app.FuelSystemCdAEditField.Value = 0.23;

                app.OTPTTargetEditField_2.Value = 362;
                app.FTPTTargetEditField_2.Value = 358;
                app.OIPTTargetEditField_2.Value = 353;
                app.FIPTTargetEditField_2.Value = 283;
                app.CHPTTargetEditField_2.Value = 260;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'09.21.24 Fuel Flows')
                app.LOXInjectorCdAEditField.Value = 0;
                app.LOXSystemCdAEditField.Value = 0;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.23;

                app.OTPTTargetEditField_2.Value = 0;
                app.FTPTTargetEditField_2.Value = 356;
                app.OIPTTargetEditField_2.Value = 0;
                app.FIPTTargetEditField_2.Value = 315;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.28.24 Regen Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.313;
                app.LOXSystemCdAEditField.Value = 0.923;
                app.FuelInjectorCdAEditField.Value = 0.162;
                app.FuelSystemCdAEditField.Value = 0.234;

                app.OTPTTargetEditField_2.Value = 335;
                app.FTPTTargetEditField_2.Value = 400;
                app.OIPTTargetEditField_2.Value = 327;
                app.FIPTTargetEditField_2.Value = 312;
                app.CHPTTargetEditField_2.Value = 219;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.28.24 Ablative Fire')
                app.LOXInjectorCdAEditField.Value = 0.313;
                app.LOXSystemCdAEditField.Value = 0.800;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.21;

                app.OTPTTargetEditField_2.Value = 360;
                app.FTPTTargetEditField_2.Value = 393;
                app.OIPTTargetEditField_2.Value = 356;
                app.FIPTTargetEditField_2.Value = 316;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.27.24 Pintle Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.3155;
                app.LOXSystemCdAEditField.Value = 0.2431;
                app.FuelInjectorCdAEditField.Value = 0.21;
                app.FuelSystemCdAEditField.Value = 0.2220;

                app.OTPTTargetEditField_2.Value = 326;
                app.FTPTTargetEditField_2.Value = 405;
                app.OIPTTargetEditField_2.Value = 320;
                app.FIPTTargetEditField_2.Value = 330;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.20.24 Subscale Coax 5')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.84;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.232;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 345;
                app.OIPTTargetEditField_2.Value = 240;
                app.FIPTTargetEditField_2.Value = 270;
                app.CHPTTargetEditField_2.Value = 200;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Note: Same as 04.20.24 Subscale Coax 4. Check mdots, Notion data seems wrong.';
            end
            if strcmp(value,'04.20.24 Subscale Coax 4')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.84;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.232;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 345;
                app.OIPTTargetEditField_2.Value = 240;
                app.FIPTTargetEditField_2.Value = 270;
                app.CHPTTargetEditField_2.Value = 200;
                app.TargetLOXmdotEditField_2.Value = 0.95;
                app.TargetFuelmdotEditField_2.Value = 0.53;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Note: Same as 04.20.24 Subscale Coax 5.';
            end
            if strcmp(value,'04.13.24 Coax Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.835;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.215;

                app.OTPTTargetEditField_2.Value = 390;
                app.FTPTTargetEditField_2.Value = 370;
                app.OIPTTargetEditField_2.Value = 381;
                app.FIPTTargetEditField_2.Value = 316;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Note: Check tank pressures, Notion data seems wrong.';
            end
            if strcmp(value,'<no input selected>')
                app.LOXInjectorCdAEditField.Value = 0;
                app.LOXSystemCdAEditField.Value = 0;
                app.FuelInjectorCdAEditField.Value = 0;
                app.FuelSystemCdAEditField.Value = 0;

                app.OTPTTargetEditField_2.Value = 0;
                app.FTPTTargetEditField_2.Value = 0;
                app.OIPTTargetEditField_2.Value = 0;
                app.FIPTTargetEditField_2.Value = 0;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 0;
                app.TargetFuelmdotEditField_2.Value = 0;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 0;

                app.EngineClassDropDown.Value = '<no input selected>';
                app.FlowMeterremovedCheckBox.Value = false;
                app.AVINouveauCheckBox.Value = false;

                app.NotesLabel.Text = 'Notes: ';
            end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.file, app.filepath] = uigetfile({'*.csv';'*.mat'}, 'unga bunga', '..\HetsAnalysis\');
            app.datacsvEditField.Value = ['Loaded file: ' app.file];
        end

        % Button pushed function: FrankButton
        function FrankButtonPushed(app, event)
            frankfile = fullfile('NASA_CEA_Package', 'franknobg.png');
            frankim = imread(frankfile, BackgroundColor=[1 1 1]);
            image(frankim)
            annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', 'I got thick thighs - Francis Frazier')
        end

        % Button pushed function: SaveOpenButton
        function SaveOpenButtonPushed(app, event)
            g = findobj('type', 'figure');
            h = findobj('type','axes');
            
            for i = 1:length(h)
                saveas(g(i), h(i).Title.String);
                saveas(g(i), h(i).Title.String, 'png');
            end
        end

        % Button pushed function: MakePowerPointButton
        function MakePowerPointButtonPushed(app, event)
            import mlreportgen.ppt.*;
            ppt = Presentation('sampleTDR.pptx');
            open(ppt);

            titleSlide = add(ppt,'Title Slide');
            today =  datestr(now, 'mm/dd/yyyy');
            replace(titleSlide,'Title', [today ' TDR']);
            replace(titleSlide,'Subtitle', 'Jackson Stahl');

            g = findobj('type', 'figure');
            h = findobj('type','axes');
            
            for i = 1:length(h)
                app.slidecounterEditField.Value = num2str(i);
                slide = add(ppt, 'Title and Picture');
                replace(slide, 'Title', h(i).Title.String)
                saveas(g(i), ['temp' num2str(i) '.png']);
                temp = Picture(['temp' num2str(i) '.png']);
                temp.Width='3in';
                temp.Height='40in';
                replace(slide, 'Picture', temp)
            end
            
            app.slidecounterEditField.Value = 'done!';

            close(ppt);
            rptview(ppt);

            app.slidecounterEditField.Value = 'done fr';
        end

        % Button pushed function: CdAsButton_2
        function CdAsButton_2Pushed(app, event)
            if app.lox~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.lox_sys_cda_orifice, '-b')
                plot(app.times, zeros(length(app.lox_sys_cda_orifice),1)+str2double(app.AvgLOXSysCdAOrificeEditField.Value), '--b');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.LOXSystemCdAEditField.Value, 'k.');
                title('LOX System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.lox_sys_cda*1.5])
                hold off

                % Plot Injector CdA
                OIPT_mask = app.OIPT > 10;
                figure
                hold on
                thesetimes = app.times(OIPT_mask);
                plot(thesetimes, app.lox_orf_cda_sys_cda(OIPT_mask), '-c');
                plot(thesetimes, zeros(length(app.lox_orf_cda_sys_cda(OIPT_mask)),1)+str2double(app.AvgLOXOrfCdASysCdAEditField.Value), '--c');
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.LOXInjectorCdAEditField.Value, 'k.');
                title('LOX Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('System CdA', 'Injector CdA', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                hold off
            end

            if app.jeta~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.fuel_sys_cda_orifice, '-b');
                plot(app.times, zeros(length(app.fuel_sys_cda_orifice),1)+str2double(app.AvgFuelSysCdAOrificeEditField.Value), '--b');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.FuelSystemCdAEditField.Value, 'k.');
                title('Fuel System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                hold off

                % Plot Injector CdA
                FIPT_mask = app.FIPT > 10;
                figure
                hold on
                thesetimes = app.times(FIPT_mask);
                plot(thesetimes, app.fuel_orf_cda_sys_cda(FIPT_mask), '-c');
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.FuelInjectorCdAEditField.Value, 'k.');
                title('Fuel Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.fuel_orifice_cda*1.5])
                legend('System CdA', 'Injector CdA', Location='northwest')
                hold off
            end
        end

        % Button pushed function: mdotsButton_2
        function mdotsButton_2Pushed(app, event)
            if app.lox~=0
                % % LOX Mdot Plot
                figure
                hold on
                plot(app.times, app.lox_mdot_cda, '-b');
                plot(app.times, app.lox_mdot_sys_cda, '-c');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetLOXmdotEditField.Value, 'k.');
                plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotorfTextArea.Value), 'b--');
                plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotsysTextArea.Value), 'c--');
                title('LOX Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                ylim([0 3])
                legend('Injector CdA', 'System CdA')
                hold off
            end

            if app.jeta~=0
                % % Fuel Mdot Plot
                figure
                hold on
                plot(app.times, app.fuel_mdot_cda, '-b');
                plot(app.times, app.fuel_mdot_sys_cda, '-c');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetFuelmdotEditField.Value, 'k.');
                plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotorfTextArea.Value), '--b');
                plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotsysTextArea.Value), '--c');
                title('Fuel Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                legend('Injector CdA', 'System CdA')
                hold off
            end
        end

        % Button pushed function: ThrustButton_2
        function ThrustButton_2Pushed(app, event)
            figure
            hold on
            plot(app.times, app.THRUST)
            title('Thrust')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Thrust (lbf)')
            xlabel('Time (s)')
            legend('Thrust')
            hold off
        end

        % Button pushed function: BangBangsButton
        function BangBangsButtonPushed(app, event)
            figure
            hold on
            yyaxis right
            plot(app.times, app.FBB, 'b')
            plot(app.times, app.OBB, 'r')
            ylabel('Actuation boolean')
            yyaxis left
            plot(app.times, app.FTPT, 'b--')
            plot(app.times, app.OTPT, 'r--') 
            ylabel('Pressure (psi)')
            title('Bang-Bang Actuations')
            subtitle(app.data_name, 'Interpreter', 'none')
            xlabel('Time (s)')
            legend('FBB', 'OBB')
            hold off
        end

        % Button pushed function: FuelPTsButton
        function FuelPTsButtonPushed(app, event)
            figure
            hold on
            plot(app.times, app.FTPT)
            plot(app.times, app.FIPT)
            plot(app.times, app.FIPT2)
            plot(app.times, app.CHPT)
            title('Fuel Pressure Ladder')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psi)')
            xlabel('Time (s)')
            legend('FTPT', 'FIPT', 'FIPT2', 'CHPT')
            hold off
        end

        % Button pushed function: LOXPTsButton
        function LOXPTsButtonPushed(app, event)
            figure
            hold on
            plot(app.times, app.OTPT)
            plot(app.times, app.OIPT)
            plot(app.times, app.CHPT)
            title('LOX Pressure Ladder')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psi)')
            xlabel('Time (s)')
            legend('OTPT', 'OIPT', 'CHPT')
            hold off
        end

        % Button pushed function: PNPTButton
        function PNPTButtonPushed(app, event)
            figure
            hold on
            plot(app.times, app.PNPT)
            title('Pneumatics Pressure')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psi)')
            xlabel('Time (s)')
            legend('PNPT')
            hold off
        end

        % Button pushed function: PRPTButton
        function PRPTButtonPushed(app, event)
            figure
            hold on
            plot(app.times, app.PRPT)
            title('Pressurant Pressure')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psi)')
            xlabel('Time (s)')
            legend('PRPT')
            hold off
        end

        % Button pushed function: TemperaturesButton_2
        function TemperaturesButton_2Pushed(app, event)
            figure
            hold on
            plot(app.times, app.OURTD)
            plot(app.times, app.ORTD)
            plot(app.times, app.OIRTD)
            title('LOX Temperatures')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Temperatures (K)')
            xlabel('Time (s)')
            legend('OURTD', 'ORTD', 'OIRTD')
            hold off
        end

        % Button pushed function: PlotEntryButton
        function PlotEntryButtonPushed(app, event)
            this = [];
            other = app.SeparateentriesbyspacesEditField.Value;
            if contains(other, '|')
                other = split(other, '|');
                leftax = other{1};
                rightax = other{2};

                len1 = length(strfind(leftax, ' ')) + 1;
                len2 = length(strfind(rightax, ' ')) + 1;
                that1 = split(leftax);
                that2 = split(rightax);
    
                figure
                hold on
                yyaxis left
                for i = 1:len1
                    plot(app.times, eval(['app.', that1{i}]))
                end
                yyaxis right
                for i = 1:len2
                    plot(app.times, eval(['app.', that2{i}]))
                end
                title('Entry Plot')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                legend([that1, that2])
                hold off
            else
                len = length(strfind(other, ' ')) + 1;
                that = split(other);
    
                figure
                hold on
                for i = 1:len
                    curr = eval(['app.', that{i}])
                    plot(app.times, curr)
                end
                title('Entry Plot')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                legend(that)
                hold off
            end
        end

        % Button pushed function: PlotEntryButton_2
        function PlotEntryButton_2Pushed(app, event)
            this = [];
            other = app.SeparateentriesbyspacesEditField_2.Value;
            if contains(other, '|')
                other = split(other, '|');
                leftax = other{1};
                rightax = other{2};
                len1 = length(strfind(leftax, ' ')) + 1;
                len2 = length(strfind(rightax, ' ')) + 1;
                that1 = split(leftax);
                that2 = split(rightax);
    
                leftColors = [
                    0.0000, 0.4470, 0.7410;
                    0.8500, 0.3250, 0.0980;
                    0.9290, 0.6940, 0.1250;
                    0.4940, 0.1840, 0.5560;
                    0.4660, 0.6740, 0.1880;
                    0.3010, 0.7450, 0.9330;
                    0.6350, 0.0780, 0.1840;
                    0.0000, 0.5000, 0.0000;
                    0.7500, 0.0000, 0.7500;
                    0.7500, 0.7500, 0.0000;
                    0.2500, 0.2500, 0.2500;
                    0.0000, 0.7500, 0.7500;
                    0.8700, 0.4900, 0.0000;
                    0.5600, 0.0000, 0.5700;
                    0.2300, 0.5700, 0.8300;
                    0.7700, 0.6900, 0.3400;
                    0.6000, 0.3100, 0.6400;
                    0.9900, 0.5100, 0.5800;
                    0.1000, 0.6000, 0.6000;
                    0.7000, 0.7000, 0.7000];

                figure
                hold on

                yyaxis left
                for i = 1:min(len1, size(leftColors,1))
                    plot(app.times, eval(['app.', that1{i}]), ...
                        'Color', leftColors(i,:), ...
                        'LineWidth', 1, ...
                        'LineStyle', '-', ...
                        'Marker', 'none');   % Disable markers entirely
                end

                yyaxis right
                for i = 1:len2
                    plot(app.times, eval(['app.', that2{i}]), ...
                         'LineWidth', 1, ...
                         'LineStyle', '-');  % solid line for consistency
                end

                title('Entry Plot')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                legend([that1; that2])
                hold off
            else
                len = length(strfind(other, ' ')) + 1;
                that = split(other);
    
                figure
                hold on
                for i = 1:len
                    plot(app.times, eval(['app.', that{i}]))
                end
                title('Entry Plot')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                legend(that)
                hold off
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StahlDataAnalysisUIFigure = uifigure('Visible', 'off');
            app.StahlDataAnalysisUIFigure.Name = 'Stahl Data Analysis';
            app.TabGroup = uitabgroup(app.StahlDataAnalysisUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.OMVTimeTextAreaLabel.Text = 'OMV Time';
            app.OMVTimeTextArea = uitextarea(app.MainTab);
            app.OMVTimeTextArea.Value = {'--'};
            app.FMVTimeTextArea = uitextarea(app.MainTab);
            app.FMVTimeTextArea.Value = {'--'};
            app.LOXCheckBox = uicheckbox(app.MainTab);
            app.LOXCheckBox.ValueChangedFcn = createCallbackFcn(app, @LOXCheckBoxValueChanged, true);
            app.LOXCheckBox.Text = 'LOX';
            app.LOXCheckBox.Value = true;
            app.LN2CheckBox = uicheckbox(app.MainTab);
            app.LN2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LN2CheckBoxValueChanged, true);
            app.LN2CheckBox.Text = 'LN2';
            app.JetACheckBox = uicheckbox(app.MainTab);
            app.JetACheckBox.ValueChangedFcn = createCallbackFcn(app, @JetACheckBoxValueChanged, true);
            app.JetACheckBox.Text = 'Jet-A';
            app.JetACheckBox.Value = true;
            app.WaterCheckBox = uicheckbox(app.MainTab);
            app.WaterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaterCheckBoxValueChanged, true);
            app.WaterCheckBox.Text = 'Water';
            app.datacsvEditField = uieditfield(app.MainTab, 'text');
            app.datacsvEditField.Value = '<load file here>';
            app.FMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.FMVTimeTextAreaLabel.Text = 'FMV Time';
            app.datacsvEditFieldLabel = uilabel(app.MainTab);
            app.datacsvEditFieldLabel.Text = 'data file';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Value = {'Not running.'};
            app.PNPTTextArea = uitextarea(app.MainTab);
            app.PNPTTextArea.Value = {'--'};
            app.PNPTTextAreaLabel = uilabel(app.MainTab);
            app.PNPTTextAreaLabel.Text = 'PNPT';
            app.loxorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.loxorfcdaTextAreaLabel.Text = 'LOX Orifice CdA';
            app.loxsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.loxsyscdaTextAreaLabel.Text = 'LOX System CdA';
            app.venturicdTextAreaLabel = uilabel(app.MainTab);
            app.venturicdTextAreaLabel.Text = 'Venturi Cd';
            app.cm2Label = uilabel(app.MainTab);
            app.cm2Label.Text = 'cm2';
            app.cm2Label_2 = uilabel(app.MainTab);
            app.cm2Label_2.Text = 'cm2';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = '--';
            app.fuelorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelorfcdaTextAreaLabel.Text = 'Fuel Orifice CdA';
            app.fuelsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelsyscdaTextAreaLabel.Text = 'Fuel System CdA';
            app.cm2Label_3 = uilabel(app.MainTab);
            app.cm2Label_3.Text = 'cm2';
            app.cm2Label_4 = uilabel(app.MainTab);
            app.cm2Label_4.Text = 'cm2';
            app.msLabel = uilabel(app.MainTab);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.MainTab);
            app.msLabel_2.Text = 'ms';
            app.psiLabel = uilabel(app.MainTab);
            app.psiLabel.Text = 'psi';
            app.loxorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.loxsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea.Value = 0.978;
            app.fuelorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.fuelsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.omvflowdurTextArea = uitextarea(app.MainTab);
            app.omvflowdurTextArea.Value = {'--'};
            app.omvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.omvflowdurTextAreaLabel.Text = 'OMV Flow Duration';
            app.sLabel_2 = uilabel(app.MainTab);
            app.sLabel_2.Text = 's';
            app.fmvflowdurTextArea = uitextarea(app.MainTab);
            app.fmvflowdurTextArea.Value = {'--'};
            app.fmvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.fmvflowdurTextAreaLabel.Text = 'FMV Flow Duration';
            app.sLabel_3 = uilabel(app.MainTab);
            app.sLabel_3.Text = 's';
            app.SamplingRateTextArea = uitextarea(app.MainTab);
            app.SamplingRateTextArea.Value = {'--'};
            app.SamplingRateTextAreaLabel = uilabel(app.MainTab);
            app.SamplingRateTextAreaLabel.Text = 'Sampling Rate';
            app.HzLabel = uilabel(app.MainTab);
            app.HzLabel.Text = 'Hz';
            app.TEditFieldLabel = uilabel(app.MainTab);
            app.TEditFieldLabel.Text = 'T-';
            app.TEditField = uieditfield(app.MainTab, 'numeric');
            app.TEditField.Value = 2;
            app.TLabel = uilabel(app.MainTab);
            app.TLabel.Text = 'T+';
            app.TEditField_2 = uieditfield(app.MainTab, 'numeric');
            app.TEditField_2.Value = 8;
            app.OrificeandinjectorusedinterchangablyinoutputsLabel = uilabel(app.MainTab);
            app.OrificeandinjectorusedinterchangablyinoutputsLabel.Text = '*Orifice and injector used interchangably in outputs';
            app.madebyJacksonStahlLabel = uilabel(app.MainTab);
            app.madebyJacksonStahlLabel.Text = 'made by Jackson Stahl';
            app.InputsLabel = uilabel(app.MainTab);
            app.InputsLabel.Text = 'Inputs:';
            app.versionLabel = uilabel(app.MainTab);
            app.versionLabel.Text = 'v0.9.1';
            app.LOXLeadLabel = uilabel(app.MainTab);
            app.LOXLeadLabel.Text = 'LOX Lead';
            app.LOXLeadEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXLeadEditField.Value = 100;
            app.OMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.OMVTimeEditFieldLabel.Text = 'OMV Time';
            app.OMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.OMVTimeEditField.Value = 130;
            app.FMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.FMVTimeEditFieldLabel.Text = 'FMV Time';
            app.FMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.FMVTimeEditField.Value = 120;
            app.msLabel_3 = uilabel(app.MainTab);
            app.msLabel_3.Text = 'ms';
            app.LOXLeadEditField_3Label = uilabel(app.MainTab);
            app.LOXLeadEditField_3Label.Text = 'LOX Lead';
            app.LOXLeadEditField_3 = uieditfield(app.MainTab, 'text');
            app.LOXLeadEditField_3.Value = '--';
            app.OutputsLabel = uilabel(app.MainTab);
            app.OutputsLabel.Text = 'Outputs:';
            app.fmmultiplierTextAreaLabel = uilabel(app.MainTab);
            app.fmmultiplierTextAreaLabel.Text = 'FM Correction Factor';
            app.Label_2 = uilabel(app.MainTab);
            app.Label_2.Text = '--';
            app.fmmultiplierTextArea = uieditfield(app.MainTab, 'numeric');
            app.fmmultiplierTextArea.Value = 1;
            app.LOXLN2densityEditFieldLabel = uilabel(app.MainTab);
            app.LOXLN2densityEditFieldLabel.Text = 'LOX/LN2 density';
            app.LOXLN2densityEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXLN2densityEditField.Value = 1077;
            app.kgm3Label = uilabel(app.MainTab);
            app.kgm3Label.Text = 'kg/m3';
            app.OTPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.OTPTTargetEditFieldLabel.Text = 'OTPT Target';
            app.OTPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.FTPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.FTPTTargetEditFieldLabel.Text = 'FTPT Target';
            app.FTPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.OIPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.OIPTTargetEditFieldLabel.Text = 'OIPT Target';
            app.OIPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.FIPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.FIPTTargetEditFieldLabel.Text = 'FIPT Target';
            app.FIPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.CHPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.CHPTTargetEditFieldLabel.Text = 'CHPT Target';
            app.CHPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetLOXmdotEditFieldLabel = uilabel(app.MainTab);
            app.TargetLOXmdotEditFieldLabel.Text = 'Target LOX mdot';
            app.TargetLOXmdotEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetLOXmdotEditField.Value = 3.2;
            app.TargetFuelmdotEditFieldLabel = uilabel(app.MainTab);
            app.TargetFuelmdotEditFieldLabel.Text = 'Target Fuel mdot';
            app.TargetFuelmdotEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetFuelmdotEditField.Value = 1.61;
            app.CalculateCheckBox_2 = uicheckbox(app.MainTab);
            app.CalculateCheckBox_2.Text = 'Calculate';
            app.CalculateCheckBox_2.Value = true;
            app.CalculateCheckBox = uicheckbox(app.MainTab);
            app.CalculateCheckBox.Text = 'Calculate';
            app.CalculateCheckBox.Value = true;
            app.DatasetInputLabel = uilabel(app.MainTab);
            app.DatasetInputLabel.Text = '<no input selected>';
            app.LoadFileButton = uibutton(app.MainTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.CheckBox = uicheckbox(app.MainTab);
            app.CheckBox.Text = '';
            app.CollapseFactorBetaEditFieldLabel = uilabel(app.MainTab);
            app.CollapseFactorBetaEditFieldLabel.Text = 'Collapse Factor (Beta)';
            app.CollapseFactorBetaEditField = uieditfield(app.MainTab, 'numeric');
            app.SpecialboxCheckBox = uicheckbox(app.MainTab);
            app.SpecialboxCheckBox.Text = 'Special box';
            app.EngineClassDropDown = uidropdown(app.MainTab);
            app.EngineClassDropDown.Items = {'Subscale - HETS', 'Vespula - HETS', 'Vespula - Rocket'};
            app.EngineClassDropDown.Value = 'Subscale - HETS';
            app.PlotsHETSTab = uitab(app.TabGroup);
            app.PlotsHETSTab.Title = 'Plots - HETS';
            app.defunctButton = uibutton(app.PlotsHETSTab, 'push');
            app.defunctButton.ButtonPushedFcn = createCallbackFcn(app, @defunctButtonPushed, true);
            app.defunctButton.Text = '<defunct>';
            app.PurgeButton = uibutton(app.PlotsHETSTab, 'push');
            app.PurgeButton.ButtonPushedFcn = createCallbackFcn(app, @PurgeButtonPushed, true);
            app.PurgeButton.Text = 'Purge';
            app.PressureLaddersButton = uibutton(app.PlotsHETSTab, 'push');
            app.PressureLaddersButton.ButtonPushedFcn = createCallbackFcn(app, @PressureLaddersButtonPushed, true);
            app.PressureLaddersButton.Text = 'Pressure Ladders';
            app.mdotsButton = uibutton(app.PlotsHETSTab, 'push');
            app.mdotsButton.ButtonPushedFcn = createCallbackFcn(app, @mdotsButtonPushed, true);
            app.mdotsButton.Text = 'mdots';
            app.CdAsButton = uibutton(app.PlotsHETSTab, 'push');
            app.CdAsButton.ButtonPushedFcn = createCallbackFcn(app, @CdAsButtonPushed, true);
            app.CdAsButton.Text = 'CdAs';
            app.ValvesButton = uibutton(app.PlotsHETSTab, 'push');
            app.ValvesButton.ButtonPushedFcn = createCallbackFcn(app, @ValvesButtonPushed, true);
            app.ValvesButton.Text = 'Valves';
            app.ThrustButton = uibutton(app.PlotsHETSTab, 'push');
            app.ThrustButton.ButtonPushedFcn = createCallbackFcn(app, @ThrustButtonPushed, true);
            app.ThrustButton.Text = 'Thrust';
            app.PRPT1CheckBox = uicheckbox(app.PlotsHETSTab);
            app.PRPT1CheckBox.Text = 'PRPT1';
            app.PRPT2CheckBox = uicheckbox(app.PlotsHETSTab);
            app.PRPT2CheckBox.Text = 'PRPT2';
            app.OTPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.OTPTCheckBox.Text = 'OTPT';
            app.OTPTCheckBox.Value = true;
            app.ORUNPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.ORUNPTCheckBox.Text = 'ORUNPT';
            app.ORUNPTCheckBox.Value = true;
            app.OIPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.OIPTCheckBox.Text = 'OIPT';
            app.OIPTCheckBox.Value = true;
            app.FTPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FTPTCheckBox.Text = 'FTPT';
            app.FTPTCheckBox.Value = true;
            app.FRUNPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FRUNPTCheckBox.Text = 'FRUNPT';
            app.FRUNPTCheckBox.Value = true;
            app.FIPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FIPTCheckBox.Text = 'FIPT';
            app.FIPTCheckBox.Value = true;
            app.CHPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.CHPTCheckBox.Text = 'CHPT';
            app.CHPTCheckBox.Value = true;
            app.FLPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FLPTCheckBox.Text = 'FLPT';
            app.FINPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FINPTCheckBox.Text = 'FINPT';
            app.ShowinputsCheckBox_2 = uicheckbox(app.PlotsHETSTab);
            app.ShowinputsCheckBox_2.Text = 'Show inputs';
            app.CloseAllButton = uibutton(app.PlotsHETSTab, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.EngineDataButton = uibutton(app.PlotsHETSTab, 'push');
            app.EngineDataButton.ButtonPushedFcn = createCallbackFcn(app, @EngineDataButtonPushed, true);
            app.EngineDataButton.Text = 'Engine Data';
            app.avgloxmdotorfTextArea = uitextarea(app.PlotsHETSTab);
            app.avgloxmdotorfTextArea.Value = {'--'};
            app.avgloxmdotorfTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgloxmdotorfTextAreaLabel.Text = 'Avg LOX Mdot (Orf CdA)';
            app.kgsLabel = uilabel(app.PlotsHETSTab);
            app.kgsLabel.Text = 'kg/s';
            app.avgloxmdotsysTextArea = uitextarea(app.PlotsHETSTab);
            app.avgloxmdotsysTextArea.Value = {'--'};
            app.avgloxmdotsysTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgloxmdotsysTextAreaLabel.Text = 'Avg LOX Mdot (Sys CdA)';
            app.kgsLabel_2 = uilabel(app.PlotsHETSTab);
            app.kgsLabel_2.Text = 'kg/s';
            app.avgloxmdotventuriTextArea = uitextarea(app.PlotsHETSTab);
            app.avgloxmdotventuriTextArea.Value = {'--'};
            app.avgloxmdotventuriTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgloxmdotventuriTextAreaLabel.Text = 'Avg LOX Mdot (Venturi)';
            app.kgsLabel_3 = uilabel(app.PlotsHETSTab);
            app.kgsLabel_3.Text = 'kg/s';
            app.avgfuelmdotorfTextArea = uitextarea(app.PlotsHETSTab);
            app.avgfuelmdotorfTextArea.Value = {'--'};
            app.avgfuelmdotorfTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgfuelmdotorfTextAreaLabel.Text = 'Avg Fuel Mdot (Orf CdA)';
            app.kgsLabel_4 = uilabel(app.PlotsHETSTab);
            app.kgsLabel_4.Text = 'kg/s';
            app.avgfuelmdotsysTextArea = uitextarea(app.PlotsHETSTab);
            app.avgfuelmdotsysTextArea.Value = {'--'};
            app.avgfuelmdotsysTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgfuelmdotsysTextAreaLabel.Text = 'Avg Fuel Mdot (Sys CdA)';
            app.kgsLabel_5 = uilabel(app.PlotsHETSTab);
            app.kgsLabel_5.Text = 'kg/s';
            app.avgfuelmdotfmTextArea = uitextarea(app.PlotsHETSTab);
            app.avgfuelmdotfmTextArea.Value = {'--'};
            app.avgfuelmdotfmTextAreaLabel = uilabel(app.PlotsHETSTab);
            app.avgfuelmdotfmTextAreaLabel.Text = 'Avg Fuel Mdot (Flow Meter)';
            app.kgsLabel_6 = uilabel(app.PlotsHETSTab);
            app.kgsLabel_6.Text = 'kg/s';
            app.cm2Label_5 = uilabel(app.PlotsHETSTab);
            app.cm2Label_5.Text = 'cm2';
            app.cm2Label_6 = uilabel(app.PlotsHETSTab);
            app.cm2Label_6.Text = 'cm2';
            app.cm2Label_7 = uilabel(app.PlotsHETSTab);
            app.cm2Label_7.Text = 'cm2';
            app.cm2Label_8 = uilabel(app.PlotsHETSTab);
            app.cm2Label_8.Text = 'cm2';
            app.cm2Label_9 = uilabel(app.PlotsHETSTab);
            app.cm2Label_9.Text = 'cm2';
            app.cm2Label_10 = uilabel(app.PlotsHETSTab);
            app.cm2Label_10.Text = 'cm2';
            app.cm2Label_11 = uilabel(app.PlotsHETSTab);
            app.cm2Label_11.Text = 'cm2';
            app.cm2Label_12 = uilabel(app.PlotsHETSTab);
            app.cm2Label_12.Text = 'cm2';
            app.AvgLOXSysCdAOrificeEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgLOXSysCdAOrificeEditFieldLabel.Text = 'Avg LOX Sys CdA (Orifice)';
            app.AvgLOXSysCdAOrificeEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgLOXSysCdAOrificeEditField.Value = '--';
            app.AvgLOXSysCdAVenturiEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgLOXSysCdAVenturiEditFieldLabel.Text = 'Avg LOX Sys CdA (Venturi)';
            app.AvgLOXSysCdAVenturiEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgLOXSysCdAVenturiEditField.Value = '--';
            app.AvgLOXOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgLOXOrfCdASysCdAEditFieldLabel.Text = 'Avg LOX Orf CdA (Sys CdA)';
            app.AvgLOXOrfCdASysCdAEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgLOXOrfCdASysCdAEditField.Value = '--';
            app.AvgLOXOrfCdAVenturiEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgLOXOrfCdAVenturiEditFieldLabel.Text = 'Avg LOX Orf CdA (Venturi)';
            app.AvgLOXOrfCdAVenturiEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgLOXOrfCdAVenturiEditField.Value = '--';
            app.AvgFuelSysCdAOrificeEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgFuelSysCdAOrificeEditFieldLabel.Text = 'Avg Fuel Sys CdA (Orifice)';
            app.AvgFuelSysCdAOrificeEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgFuelSysCdAOrificeEditField.Value = '--';
            app.AvgFuelOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgFuelOrfCdASysCdAEditFieldLabel.Text = 'Avg Fuel Orf CdA (Sys CdA)';
            app.AvgFuelOrfCdASysCdAEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgFuelOrfCdASysCdAEditField.Value = '--';
            app.AvgFuelSysCdAFlowMeterEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgFuelSysCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Sys CdA (Flow Meter)';
            app.AvgFuelSysCdAFlowMeterEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel = uilabel(app.PlotsHETSTab);
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Orf CdA (Flow Meter)';
            app.AvgFuelOrfCdAFlowMeterEditField = uieditfield(app.PlotsHETSTab, 'text');
            app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';
            app.ShowinputsCheckBox = uicheckbox(app.PlotsHETSTab);
            app.ShowinputsCheckBox.Text = 'Show inputs';
            app.TemperaturesButton = uibutton(app.PlotsHETSTab, 'push');
            app.TemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @TemperaturesButtonPushed, true);
            app.TemperaturesButton.Text = 'Temperatures';
            app.PNPTCheckBox = uicheckbox(app.PlotsHETSTab);
            app.PNPTCheckBox.Text = 'PNPT';
            app.PNPTCheckBox.Value = true;
            app.FLOWCheckBox = uicheckbox(app.PlotsHETSTab);
            app.FLOWCheckBox.Text = 'FLOW';
            app.ODPCheckBox = uicheckbox(app.PlotsHETSTab);
            app.ODPCheckBox.Text = 'ODP';
            app.WithPressuresCheckBox = uicheckbox(app.PlotsHETSTab);
            app.WithPressuresCheckBox.Text = 'With Pressures';
            app.ShowdatatipsCheckBox = uicheckbox(app.PlotsHETSTab);
            app.ShowdatatipsCheckBox.Text = 'Show datatips';
            app.ShowdatatipsCheckBox_2 = uicheckbox(app.PlotsHETSTab);
            app.ShowdatatipsCheckBox_2.Text = 'Show datatips';
            app.PRPTOCheckBox = uicheckbox(app.PlotsHETSTab);
            app.PRPTOCheckBox.Text = 'PRPTO';
            app.PRPTFCheckBox = uicheckbox(app.PlotsHETSTab);
            app.PRPTFCheckBox.Text = 'PRPTF';
            app.SaveOpenButton = uibutton(app.PlotsHETSTab, 'push');
            app.SaveOpenButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOpenButtonPushed, true);
            app.SaveOpenButton.Text = 'Save Open';
            app.CEfficiencyButton = uibutton(app.PlotsHETSTab, 'push');
            app.CEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @CEfficiencyButtonPushed, true);
            app.CEfficiencyButton.Text = 'C* Efficiency';
            app.SeparateentriesbyspacesEditField_2Label = uilabel(app.PlotsHETSTab);
            app.SeparateentriesbyspacesEditField_2Label.Text = 'Separate entries by spaces';
            app.SeparateentriesbyspacesEditField_2 = uieditfield(app.PlotsHETSTab, 'text');
            app.PlotEntryButton_2 = uibutton(app.PlotsHETSTab, 'push');
            app.PlotEntryButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotEntryButton_2Pushed, true);
            app.PlotEntryButton_2.Text = 'Plot Entry';
            app.PlotsVespulaTab = uitab(app.TabGroup);
            app.PlotsVespulaTab.Title = 'Plots - Vespula';
            app.CdAsButton_2 = uibutton(app.PlotsVespulaTab, 'push');
            app.CdAsButton_2.ButtonPushedFcn = createCallbackFcn(app, @CdAsButton_2Pushed, true);
            app.CdAsButton_2.Text = 'CdAs';
            app.mdotsButton_2 = uibutton(app.PlotsVespulaTab, 'push');
            app.mdotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @mdotsButton_2Pushed, true);
            app.mdotsButton_2.Text = 'mdots';
            app.FuelPTsButton = uibutton(app.PlotsVespulaTab, 'push');
            app.FuelPTsButton.ButtonPushedFcn = createCallbackFcn(app, @FuelPTsButtonPushed, true);
            app.FuelPTsButton.Text = 'Fuel PTs';
            app.LOXPTsButton = uibutton(app.PlotsVespulaTab, 'push');
            app.LOXPTsButton.ButtonPushedFcn = createCallbackFcn(app, @LOXPTsButtonPushed, true);
            app.LOXPTsButton.Text = 'LOX PTs';
            app.ThrustButton_2 = uibutton(app.PlotsVespulaTab, 'push');
            app.ThrustButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThrustButton_2Pushed, true);
            app.ThrustButton_2.Text = 'Thrust';
            app.BangBangsButton = uibutton(app.PlotsVespulaTab, 'push');
            app.BangBangsButton.ButtonPushedFcn = createCallbackFcn(app, @BangBangsButtonPushed, true);
            app.BangBangsButton.Text = 'Bang-Bangs';
            app.PNPTButton = uibutton(app.PlotsVespulaTab, 'push');
            app.PNPTButton.ButtonPushedFcn = createCallbackFcn(app, @PNPTButtonPushed, true);
            app.PNPTButton.Text = 'PNPT';
            app.PRPTButton = uibutton(app.PlotsVespulaTab, 'push');
            app.PRPTButton.ButtonPushedFcn = createCallbackFcn(app, @PRPTButtonPushed, true);
            app.PRPTButton.Text = 'PRPT';
            app.PlotEntryLabel = uilabel(app.PlotsVespulaTab);
            app.PlotEntryLabel.Text = 'Separate entries by spaces';
            app.SeparateentriesbyspacesEditField = uieditfield(app.PlotsVespulaTab, 'text');
            app.TemperaturesButton_2 = uibutton(app.PlotsVespulaTab, 'push');
            app.TemperaturesButton_2.ButtonPushedFcn = createCallbackFcn(app, @TemperaturesButton_2Pushed, true);
            app.TemperaturesButton_2.Text = 'Temperatures';
            app.VespulaNoteTextAreaLabel = uilabel(app.PlotsVespulaTab);
            app.VespulaNoteTextAreaLabel.Text = 'Vespula Note:';
            app.VespulaNoteTextArea = uitextarea(app.PlotsVespulaTab);
            app.VespulaNoteTextArea.Value = {'You MUST put your datafiles into a folder so and select A FILE IN THE FOLDER in the main tab so the DANGUI can compile your files together. Make sure all the files in the folder are from the SAME DATASET and LISTED ALPHABETICALLY'};
            app.PlotEntryButton = uibutton(app.PlotsVespulaTab, 'push');
            app.PlotEntryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEntryButtonPushed, true);
            app.PlotEntryButton.Text = 'Plot Entry';
            app.LoadInputsTab = uitab(app.TabGroup);
            app.LoadInputsTab.Title = 'Load Inputs';
            app.LOXInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXInjectorCdAEditFieldLabel.Text = 'LOX Injector CdA';
            app.LOXInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LOXSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXSystemCdAEditFieldLabel.Text = 'LOX System CdA';
            app.LOXSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelInjectorCdAEditFieldLabel.Text = 'Fuel Injector CdA';
            app.FuelInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelSystemCdAEditFieldLabel.Text = 'Fuel System CdA';
            app.FuelSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LoadButton = uibutton(app.LoadInputsTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DatasetLabel = uilabel(app.LoadInputsTab);
            app.DatasetLabel.Text = 'Dataset:';
            app.DatasetDropDown = uidropdown(app.LoadInputsTab);
            app.DatasetDropDown.Items = {'<no input selected>', '10.11.25 Vespula Ablative 2', '09.27.25 Regen Full', '09.21.25 Regen Blip', '09.20.25 Regen Fuel Flows', '09.13.25 Vespula Ablative', '09.07.25 HETS Activation Flows', '04.12.25 V. Heatsink Double Feature', 'what jackson thinks vespula inputs are', '02.22.25 SS HS Heat Flux 2', '02.22.25 Vespula Heatsink 3.5', '02.01.25 Vespula Heatscrub', '11.24.24 Subscale Heatsink Heat Flux', '11.16.24 Vespula Main Valves Fire', '11.16.24 Vespula Flows New Sequence', '10.19.24 Subscale Ablative V2.5', '10.19.24 Vespula Heatsink', '10.06.24 Subscale Ablative', '10.05.24 Vespula Flows', '09.28.24 Vespula Flows', '09.21.24 Subscale Coax Remachined', '09.21.24 Fuel Flows', '04.28.24 Regen Ramp Up', '04.28.24 Ablative Fire', '04.27.24 Pintle Ramp Up', '04.20.24 Subscale Coax 5', '04.20.24 Subscale Coax 4', '04.13.24 Coax Ramp Up'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = '<no input selected>';
            app.OTPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.OTPTTargetEditField_2Label.Text = 'OTPT Target';
            app.OTPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FTPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.FTPTTargetEditField_2Label.Text = 'FTPT Target';
            app.FTPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.OIPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.OIPTTargetEditField_2Label.Text = 'OIPT Target';
            app.OIPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FIPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.FIPTTargetEditField_2Label.Text = 'FIPT Target';
            app.FIPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.CHPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.CHPTTargetEditField_2Label.Text = 'CHPT Target';
            app.CHPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.TargetLOXmdotEditField_2Label = uilabel(app.LoadInputsTab);
            app.TargetLOXmdotEditField_2Label.Text = 'Target LOX mdot';
            app.TargetLOXmdotEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.TargetFuelmdotEditField_2Label = uilabel(app.LoadInputsTab);
            app.TargetFuelmdotEditField_2Label.Text = 'Target Fuel mdot';
            app.TargetFuelmdotEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.NotesLabel = uilabel(app.LoadInputsTab);
            app.NotesLabel.Text = 'Notes: ';
            app.BepatientLabel = uilabel(app.LoadInputsTab);
            app.BepatientLabel.Text = 'Be patient.';
            app.SwitchORUNandFRUNCheckBox = uicheckbox(app.LoadInputsTab);
            app.SwitchORUNandFRUNCheckBox.Text = 'Switch ORUN and FRUN';
            app.LOXLeadEditField_4Label = uilabel(app.LoadInputsTab);
            app.LOXLeadEditField_4Label.Text = 'LOX Lead';
            app.LOXLeadEditField_4 = uieditfield(app.LoadInputsTab, 'numeric');
            app.OMVTimeEditField_2Label = uilabel(app.LoadInputsTab);
            app.OMVTimeEditField_2Label.Text = 'OMV Time';
            app.OMVTimeEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FMVTimeEditField_2Label = uilabel(app.LoadInputsTab);
            app.FMVTimeEditField_2Label.Text = 'FMV Time';
            app.FMVTimeEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FlowMeterremovedCheckBox = uicheckbox(app.LoadInputsTab);
            app.FlowMeterremovedCheckBox.Text = 'Flow Meter removed';
            app.AVINouveauCheckBox = uicheckbox(app.LoadInputsTab);
            app.AVINouveauCheckBox.Text = 'AVI Nouveau';
            app.FlowMeterScalingCorrectionCheckBox = uicheckbox(app.LoadInputsTab);
            app.FlowMeterScalingCorrectionCheckBox.Text = 'Flow Meter Scaling Correction';
            app.DatainfolderonlySp25VespulaRockettestsCheckBox = uicheckbox(app.LoadInputsTab);
            app.DatainfolderonlySp25VespulaRockettestsCheckBox.Text = 'Data in folder (only Sp25 Vespula - Rocket tests)';
            app.PowerPointBetaTab = uitab(app.TabGroup);
            app.PowerPointBetaTab.Title = 'PowerPoint (Beta)';
            app.MakePowerPointButton = uibutton(app.PowerPointBetaTab, 'push');
            app.MakePowerPointButton.ButtonPushedFcn = createCallbackFcn(app, @MakePowerPointButtonPushed, true);
            app.MakePowerPointButton.Text = 'Make PowerPoint';
            app.TextArea_3 = uitextarea(app.PowerPointBetaTab);
            app.TextArea_3.Value = {'This will generate a PowerPoint from all of the open plots. It is very much in beta. To use it, you must install the MATLAB Report Generator Add-On from the Add-Ons menu in the HOME tab of MATLAB.'};
            app.slidecounterEditFieldLabel = uilabel(app.PowerPointBetaTab);
            app.slidecounterEditFieldLabel.Text = 'slide counter';
            app.slidecounterEditField = uieditfield(app.PowerPointBetaTab, 'text');
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.TextArea_2 = uitextarea(app.ChangelogTab);
            app.TextArea_2.Value = {'v0.9.1'; 'Merged Vespula test capabilities'; ''; 'v0.9.0'; 'Ananya changes 1'; 'Plot entry upgrade - can to two axes'; ''; 'v0.8.2'; 'Added Regen Fire'; ''; 'v0.8.1'; 'Added 09.21.25 Regen Fuel Flows'; ''; 'v0.8.0'; 'Integrated new AVI outputs fully into datasets, note AVI Nouveau checkbox automatically checked/unchecked'; 'Restored flow meter to same capability'; 'Added V. Ablative dataset'; 'Added Ablative TC capabilities - use Entry Plot function in Plots - Vespula tab'; 'FM plot bug fixes'; ''; 'v0.7.2'; 'Optimized for Activation Flows, may have induced other bugs'; ''; 'v0.7.1'; 'Added activation flows datasets'};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
            app.FrankButton = uibutton(app.ChangelogTab, 'push');
            app.FrankButton.ButtonPushedFcn = createCallbackFcn(app, @FrankButtonPushed, true);
            app.FrankButton.Text = 'Frank';
            % Show the figure after all components are created
            app.StahlDataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StahlDataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StahlDataAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StahlDataAnalysisUIFigure)
        end
    end
end